classdef mixed_fibers < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MixedFiberUIFigure       matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        BradykinesiaSliderLabel  matlab.ui.control.Label
        BradykinesiaSlider       matlab.ui.control.Slider
        RigiditySliderLabel      matlab.ui.control.Label
        RigiditySlider           matlab.ui.control.Slider
        GaitSliderLabel          matlab.ui.control.Label
        GaitSlider               matlab.ui.control.Slider
        TremorSliderLabel        matlab.ui.control.Label
        TremorSlider             matlab.ui.control.Slider
        OKButton                 matlab.ui.control.Button
        SelectAtlasPathButton    matlab.ui.control.Button
        CustomizeFiberVisButton  matlab.ui.control.Button
        RightPanel               matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        fig
        patch_fig = [];
        atlas_dir
        img_names
        nifti_fold
        brady_img
        rigi_img
        gait_img
        tremor_img
        mixed_nii
        weighted_brady
        weighted_rigidity
        weighted_tremor
        weighted_gait
        concatenated_img
        a
        result
        smooth
        smooth_val
        ax
        fcolor
        ecolor
        edge_alpha
        face_alpha
        ambient_light
        diffuse_light
        specular_light
        specular_exp
        % Description
    end
    
    methods (Access = public)
        
        function load_niftis(app)           
           pre_brady_img = ~cellfun('isempty', regexpi(app.img_names, 'Bradykinesia.*'));
           brady_name = app.img_names{find(pre_brady_img)};
           app.brady_img = ea_load_nii([app.nifti_fold '/' brady_name]);
           
           pre_rigi_img = ~cellfun('isempty', regexpi(app.img_names, 'Rigidity.*'));
           rigi_name = app.img_names{find(pre_rigi_img)};
           app.rigi_img = ea_load_nii([app.nifti_fold '/' rigi_name]);
           
           pre_gait_img = ~cellfun('isempty', regexpi(app.img_names, 'Axial.*'));
           gait_name = app.img_names{find(pre_gait_img)};
           app.gait_img = ea_load_nii([app.nifti_fold '/' gait_name]);
           
           pre_tremor_img = ~cellfun('isempty', regexpi(app.img_names, 'Tremor.*'));
           tremor_name = app.img_names{find(pre_tremor_img)};
           app.tremor_img = ea_load_nii([app.nifti_fold '/' tremor_name]);
           return
        end
        
        
        function calculate_mixed_nifti(app)

            sum_of_weights = 0;
            if app.BradykinesiaSlider.Value                
                %app.PercBradykinesiaEditField.Enable = 'off';
                sum_of_weights = sum_of_weights+(app.BradykinesiaSlider.Value/100);
                temp_brady = (app.BradykinesiaSlider.Value/100)*app.brady_img.img; %stores the value so that next time, you're not updating the entire thing again
                if ~isequal(temp_brady,app.weighted_brady)
                    app.weighted_brady = (app.BradykinesiaSlider.Value/100)*app.brady_img.img;
                
                end
            else
                temp_brady = [];
            end
            
            if app.RigiditySlider.Value 
                sum_of_weights = sum_of_weights+(app.RigiditySlider.Value/100);
                temp_rigi = (app.RigiditySlider.Value/100)*app.rigi_img.img;
                if ~isequal(temp_rigi,app.weighted_rigidity)
                    app.weighted_rigidity = temp_rigi;
                end
            else
                temp_rigi = [];
            end
            
            if app.GaitSlider.Value
                sum_of_weights = sum_of_weights+(app.GaitSlider.Value/100);
                temp_gait = (app.GaitSlider.Value/100)*app.gait_img.img;
                if ~isequal(temp_gait,app.weighted_gait)
                    app.weighted_gait = temp_gait;
                end
            else
                temp_gait = [];
            end
            
            if app.TremorSlider.Value
                sum_of_weights = sum_of_weights+(app.TremorSlider.Value/100);
                temp_tremor = (app.TremorSlider.Value/100)*app.tremor_img.img;
                if ~isequal(temp_tremor,app.weighted_tremor)
                   app.weighted_tremor =  temp_tremor;
                end
            else
                temp_tremor = [];
            end
            
            if sum_of_weights > 0
                %%purely for information
                if app.BradykinesiaSlider.Value
                    disp(['Weight given to Bradykinesia:' num2str(app.BradykinesiaSlider.Value)]);
                end
                if app.RigiditySlider.Value
                   disp(['Weight given to Rigidity:' num2str(app.RigiditySlider.Value)]);
                end
                if app.GaitSlider.Value
                   disp(['Weight given to Gait:' num2str(app.GaitSlider.Value)]);
                end
                if app.TremorSlider.Value
                   disp(['Weight given to Tremor:' num2str(app.TremorSlider.Value)]);
                end
                disp(['Total sum of weights:' num2str(sum_of_weights)]);
                temp_img = {temp_brady,temp_rigi,temp_gait,temp_tremor};
                if ~isequal(temp_img,app.concatenated_img)
                    app.concatenated_img = {app.weighted_brady,app.weighted_rigidity,app.weighted_gait,app.weighted_tremor};
                    app.a = find(~cellfun(@isempty,app.concatenated_img));
                    %creates a new image of zeros for the actual mixed image. This
                    %will be output into a nifti file and then will be the input
                    %for a plot command (using isosurface and patch)
                    if ~app.a
                        disp("There are no weights given, therefore the program will end now...")
                    end
                    
                    if length(app.a) == 1
                        mixed_img = app.concatenated_img{1,app.a}./sum_of_weights;
                    else
                        mixed_img = zeros(size(app.brady_img.img));
                    end
                    if length(app.a) > 1
                        for i=1:length(app.a)
                            indx = app.a(i);
                            mixed_img = mixed_img + (app.concatenated_img{1,indx}/sum_of_weights);
                        end
                    end
                    
                    ref = [ea_space,'t1.nii'];
                    refnii = ea_load_nii(ref);
                    refnii.img = mixed_img;
                    refnii.fname = [app.nifti_fold '/Mixed_nii.nii'];
                    ea_write_nii(refnii);
                    disp("done...now plotting fibers");
                    plot_mixed_fibers(app);
                else
                    disp("Using stored information...")
                    change_mixed_fibers(app);
                end

            end
        end
    end
    
    methods (Access = private)
        
        function plot_mixed_fibers(app)
            if ~isempty(app.patch_fig)
                delete(app.patch_fig);
                %set(app.patch_fig,'Visible','off')
                disp("Setting Old Patch Visibility to OFF")
                
            end
            app.ax = app.fig.CurrentAxes;
            [~,fv2] = ea_nii2fv_v2([app.nifti_fold '/Mixed_nii.nii'],1,3);
            app.patch_fig = patch(app.ax,'Faces',fv2.faces,'Vertices',fv2.vertices,'FaceColor','b','EdgeColor','b',...
                'FaceLighting','gouraud',...
                'SpecularStrength',0.3, ...
                'SpecularColorReflectance',1,'SpecularExponent',3, ...
                'DiffuseStrength',0.4, 'LineStyle','-');
            disp("Patch drawing complete!...");
          
        end
        
        function change_mixed_fibers(app)
            if app.smooth
                if ~isempty(app.patch_fig)
                    delete(app.patch_fig);
                    %set(app.patch_fig,'Visible','off')
                    disp("Setting Old Patch Visibility to OFF")
                    
                end
                disp("Re-plotting with new smoothing value")
                [~,fv2] = ea_nii2fv_v2([app.nifti_fold '/Mixed_nii.nii'],1,app.smooth_val);
                app.patch_fig = patch(app.ax,'Faces',fv2.faces,'Vertices',fv2.vertices,...
                    'FaceLighting','gouraud',...
                    'SpecularColorReflectance',1, ...
                    'LineStyle','-');
                if app.fcolor
                    app.patch_fig.FaceColor = app.fcolor;
                else
                    app.patch_fig.FaceColor = 'b';
                end
                if app.ecolor
                    app.patch_fig.EdgeColor = app.ecolor;
                else
                    app.patch_fig.EdgeColor = 'b';
                end
                if app.face_alpha
                    app.patch_fig.FaceAlpha = app.face_alpha;
                end
                if app.edge_alpha
                    app.patch_fig.EdgeAlpha = app.edge_alpha;
                end
                if app.ambient_light
                    app.patch_fig.AmbientLight = app.ambient_light;
                end
                if app.diffuse_light
                    app.patch_fig.DiffuseLight = app.diffuse_light;
                end
                if app.specular_light
                    app.patch_fig.SpecularStrength = app.specular_light;
                end
                if app.specular_exp
                    app.patch_fig.SpecularExponent = app.specular_exp;
                end
                disp("Patch Drawing Complete!")
            end
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, atlasDir, resultfig)
           app.fig = resultfig;
           if ~strcmp(atlasDir,'')
                app.atlas_dir = atlasDir;
                app.nifti_fold = [app.atlas_dir{1} '/nifti'];
                nifti_filenames = dir(app.nifti_fold);
                app.img_names = {};
                for i=1:length(nifti_filenames)
                    if endsWith(nifti_filenames(i).name,'.nii')
                        app.img_names{i} = nifti_filenames(i).name;
                    end
                end
                load_niftis(app);
                disp('Loaded all your input arguments...');
           else
               disp("Please select the Atlas folder using the Select Atlas Button")
           end


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MixedFiberUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {403, 403};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {341, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function
        function MonthlyPaymentButtonPushed(app, event)
            
        end

        % Value changed function: BradykinesiaSlider
        function BradykinesiaSliderValueChanged(app, event)
            value = app.BradykinesiaSlider.Value;
            app.BradykinesiaSlider.Tooltip = ['Value:' num2str(app.BradykinesiaSlider.Value)];
        end

        % Value changing function: BradykinesiaSlider
        function BradykinesiaSliderValueChanging(app, event)
            %app.brady_changing = event.Value;
        end

        % Callback function
        function PercBradykinesiaEditFieldValueChanged(app, event)
            app.perc_brady = app.PercBradykinesiaEditField.Value;
        end

        % Value changed function: RigiditySlider
        function RigiditySliderValueChanged(app, event)
            value = app.RigiditySlider.Value;
            app.RigiditySlider.Tooltip = ['Value:' num2str(app.RigiditySlider.Value)];
        end

        % Value changing function: RigiditySlider
        function RigiditySliderValueChanging(app, event)
            %app.rigidity_changing = event.Value;
        end

        % Callback function
        function PercRigidityEditFieldValueChanged(app, event)
           
        end

        % Value changed function: GaitSlider
        function GaitSliderValueChanged(app, event)
            app.GaitSlider.Tooltip = ['Value:' num2str(app.GaitSlider.Value)];
        end

        % Value changing function: GaitSlider
        function GaitSliderValueChanging(app, event)
            %app.gait_changing = event.Value;
        end

        % Callback function
        function PercGaitEditFieldValueChanged(app, event)
            app.perc_gait = app.PercGaitEditField.Value;
        end

        % Value changed function: TremorSlider
        function TremorSliderValueChanged(app, event)
            value = app.TremorSlider.Value;
            app.TremorSlider.Tooltip = ['Value:' num2str(app.TremorSlider.Value)];
        end

        % Value changing function: TremorSlider
        function TremorSliderValueChanging(app, event)
            %app.tremor_changing = event.Value;
        end

        % Callback function
        function PercTremorEditFieldValueChanged(app, event)
          value = app.PercTremorEditField.Value;
        end

        % Callback function
        function PercBradykinesiaEditFieldValueChanged2(app, event)
            app.perc_brady = app.PercBradykinesiaEditField.Value;
        end

        % Callback function
        function PercRigidityEditFieldValueChanged2(app, event)
           app.perc_rigidity = app.PercRigidityEditField.Value;     
        end

        % Callback function
        function PercGaitEditFieldValueChanged2(app, event)
            app.perc_gait = app.PercGaitEditField.Value;
        end

        % Callback function
        function OKButtonPushed(app, event)
           
        end

        % Button pushed function: OKButton
        function OKButtonPushed2(app, event)
            calculate_mixed_nifti(app);
        end

        % Button pushed function: SelectAtlasPathButton
        function SelectAtlasPathButtonPushed(app, event)
            selpath = uigetdir([ea_space '/atlases']);
            if isempty(app.atlas_dir)
                app.nifti_fold = [selpath '/nifti'];
                nifti_filenames = dir(app.nifti_fold);
                app.img_names = {};
                for i=1:length(nifti_filenames)
                    if endsWith(nifti_filenames(i).name,'.nii')
                        app.img_names{i} = nifti_filenames(i).name;
                    end
                end
            end
            disp(selpath)
            tic
            load_niftis(app);
            f = msgbox('Loaded all your input arguments!');
            toc
        end

        % Close request function: MixedFiberUIFigure
        function MixedFiberUIFigureCloseRequest(app, event)
            if ~isempty(app.patch_fig)
                delete(app.patch_fig)
            end
            delete(app)
            
        end

        % Callback function
        function SmoothFibersCheckBoxValueChanged(app, event)
            value = app.SmoothFibersCheckBox.Value;
            if app.SmoothFibersCheckBox.Value
                app.SmoothingFWHMEditField.Enable = 'on';
            else
                app.SmoothingFWHMEditField.Enable = 'off';
            end
        end

        % Button pushed function: CustomizeFiberVisButton
        function CustomizeFiberVisButtonPushed(app, event)
            f = uifigure('Position',[100 100 366 500],'Color','w');
            %Add Smoothing Feature
            
            
            sm_val = uieditfield(f,'numeric','Position',[195 400 70 20],'ValueChangedFcn',@(sm_val,event)  smooth_selection(app,sm_val));
            
            sm_logical = uicheckbox(f,"Text",'Smooth Fibers','Position',[150 205 100 500],'ValueChangedFcn',@(sm_logical,event) should_smooth(app,sm_logical,sm_val));
            sm_lbl = uilabel(f,'Text','Smoothing Value','Position',[97 387 100 50]);
            
            %Add color Feature
            fcolor_dd = uidropdown(f,'Items',{'BLUE','RED','GREEN','YELLOW','BLACK'},'Position',[190 350 80 20],'ValueChangedFcn',@(fcolor_dd,event) fcolor_selection(app,fcolor_dd));
            fcolor_lbl = uilabel(f,'Text','Face Color of Fiber','Position',[100 334 80 50]);
            
           
            ecolor_dd = uidropdown(f,'Items',{'BLUE','RED','GREEN','YELLOW','BLACK'},'Position',[190 310 80 20],'ValueChangedFcn',@(ecolor_dd,event) ecolor_selection(app,ecolor_dd));
            ecolor_lbl = uilabel(f,'Text','Edge Color of Fiber','Position',[100 294 80 50]);
            
            %Add face color alpha slider
            sld_facecolor = uislider(f,'Position',[50 275 120 3],'Limits',[0 1],'ValueChangedFcn',@(sld_facecolor,event) fcolor_alpha(app,sld_facecolor));
            face_lbl = uilabel(f,'Text','Select Face Alpha','Position',[60 220 115 20]);
             
            sld_edgecolor = uislider(f,'Position',[200 275 120 3],'Limits',[0 1],'ValueChangedFcn',@(sld_edgecolor,event) ecolor_alpha(app,sld_edgecolor));
            edge_lbl = uilabel(f,'Text','Select Edge Alpha','Position',[210 220 105 20]);
            
            %Lighting stuff
            ambient_str = uislider(f,'Position',[50 200 120 3],'Limits',[0 1],'ValueChangedFcn',@(ambient_str,event) selection_ambient(app,ambient_str));
            ambient_lbl = uilabel(f,'Text','Ambient Light','Position',[70 145 115 20]);
             
            diffuse_str = uislider(f,'Position',[200 200 120 3],'Limits',[0 1],'ValueChangedFcn',@(diffuse_str,event) selection_diffuse(app,diffuse_str));
            diffuse_lbl = uilabel(f,'Text','Diffuse Light','Position',[220 145 115 20]);
            
            
            specular_str = uislider(f,'Position',[50 135 120 3],'Limits',[0 1],'ValueChangedFcn',@(specular_str,event) selection_specular(app,specular_str));
            specular_lbl = uilabel(f,'Text','Specular Strength','Position',[60 75 115 20]);
             
            specular_exp = uislider(f,'Position',[200 135 120 3],'Limits',[1 10],'ValueChangedFcn',@(specular_exp,event) selection_spec_exp(app,specular_exp));
            specular_exp_lbl = uilabel(f,'Text','Specular Exponent','Position',[210 75 115 20]);
            
            function should_smooth(app,sm_logical,sm_val)
                app.smooth = sm_logical.Value;
                if app.smooth
                    sm_val.Enable = 'on';
                else
                    sm_val.Enable = 'off';
                end
            end
            function smooth_selection(app,sm_val)
                app.smooth_val = sm_val.Value;
                change_mixed_fibers(app);
            end
            
            function fcolor_selection(app,fcolor_dd)
                app.fcolor = fcolor_dd.Value;
                app.patch_fig.FaceColor = app.fcolor;
                disp("Face Color updated successfully")
                
            end
            function ecolor_selection(app,ecolor_dd)
                app.ecolor = ecolor_dd.Value;
                app.patch_fig.EdgeColor = app.ecolor;
                disp("Edge Color updated successfully")
                
            end
            function ecolor_alpha(app,sld_edgecolor)
                app.edge_alpha = sld_edgecolor.Value;
                app.patch_fig.EdgeAlpha = app.edge_alpha;
                disp("Edge Aplpha updated successfully")
            end
            function fcolor_alpha(app,sld_facecolor)
                app.face_alpha = sld_facecolor.Value;
                app.patch_fig.FaceAlpha = app.face_alpha;
                disp("Face Aplpha updated successfully")
            end
            function selection_ambient(app,ambient_str)
                app.ambient_light = ambient_str.Value;
                app.patch_fig.AmbientStrength = app.ambient_light;
                disp("Ambient Light updated successfully")
            end
            function selection_diffuse(app,diffuse_str)
                app.diffuse_light = diffuse_str.Value;
                app.patch_fig.DiffuseStrength = app.diffuse_light;
                disp("Diffuse Light updated successfully")
            end
            function selection_specular(app,specular_str)
                app.specular_light = specular_str.Value;
                app.patch_fig.SpecularStrength = app.specular_light;
                disp("Specular Strength updated successfully")
            end
            function selection_spec_exp(app,specular_exp)
                app.specular_exp = specular_exp.Value;
                app.patch_fig.SpecularExponent = app.specular_exp;
                disp("Specular Exponent updated sucessfully")
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MixedFiberUIFigure = uifigure('Visible', 'off');
            app.MixedFiberUIFigure.Name = 'Mortgage Calculator';
            app.MixedFiberUIFigure.CloseRequestFcn = createCallbackFcn(app, @MixedFiberUIFigureCloseRequest, true);
            app.MixedFiberUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.MixedFiberUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.BradykinesiaSliderLabel = uilabel(app.LeftPanel);
            app.BradykinesiaSliderLabel.Text = 'Bradykinesia';
            app.BradykinesiaSlider = uislider(app.LeftPanel);
            app.BradykinesiaSlider.ValueChangedFcn = createCallbackFcn(app, @BradykinesiaSliderValueChanged, true);
            app.BradykinesiaSlider.ValueChangingFcn = createCallbackFcn(app, @BradykinesiaSliderValueChanging, true);
            app.RigiditySliderLabel = uilabel(app.LeftPanel);
            app.RigiditySliderLabel.Text = 'Rigidity';
            app.RigiditySlider = uislider(app.LeftPanel);
            app.RigiditySlider.ValueChangedFcn = createCallbackFcn(app, @RigiditySliderValueChanged, true);
            app.RigiditySlider.ValueChangingFcn = createCallbackFcn(app, @RigiditySliderValueChanging, true);
            app.GaitSliderLabel = uilabel(app.LeftPanel);
            app.GaitSliderLabel.Text = 'Gait';
            app.GaitSlider = uislider(app.LeftPanel);
            app.GaitSlider.ValueChangedFcn = createCallbackFcn(app, @GaitSliderValueChanged, true);
            app.GaitSlider.ValueChangingFcn = createCallbackFcn(app, @GaitSliderValueChanging, true);
            app.TremorSliderLabel = uilabel(app.LeftPanel);
            app.TremorSliderLabel.Text = 'Tremor';
            app.TremorSlider = uislider(app.LeftPanel);
            app.TremorSlider.ValueChangedFcn = createCallbackFcn(app, @TremorSliderValueChanged, true);
            app.TremorSlider.ValueChangingFcn = createCallbackFcn(app, @TremorSliderValueChanging, true);
            app.OKButton = uibutton(app.LeftPanel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed2, true);
            app.OKButton.Text = 'OK';
            app.SelectAtlasPathButton = uibutton(app.LeftPanel, 'push');
            app.SelectAtlasPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAtlasPathButtonPushed, true);
            app.SelectAtlasPathButton.Text = 'Select Atlas Path';
            app.CustomizeFiberVisButton = uibutton(app.LeftPanel, 'push');
            app.CustomizeFiberVisButton.ButtonPushedFcn = createCallbackFcn(app, @CustomizeFiberVisButtonPushed, true);
            app.CustomizeFiberVisButton.Text = 'Customize Fiber. Vis';
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.MixedFiberUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mixed_fibers(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MixedFiberUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MixedFiberUIFigure)
        end
    end
end