classdef ea_score_gen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ToolsMenu                       matlab.ui.container.Menu
        GenerateExcelMenu               matlab.ui.container.Menu
        forUPDRSMenu                    matlab.ui.container.Menu
        forOtherScoretypesMenu          matlab.ui.container.Menu
        ExportasExcelMenu               matlab.ui.container.Menu
        forUPDRSMenu_2                  matlab.ui.container.Menu
        forCustomMenu                   matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        CUSTOMTab                       matlab.ui.container.Tab
        POSTOPEditField_5               matlab.ui.control.EditField
        GENERATEButton_3                matlab.ui.control.Button
        BASELINESTARTDropDown           matlab.ui.control.DropDown
        BASELINESTOPDropDown            matlab.ui.control.DropDown
        POSTOPSTARTDropDown             matlab.ui.control.DropDown
        POSTOPSTOPDropDown              matlab.ui.control.DropDown
        CALCULATECHANGESCORESCheckBox   matlab.ui.control.CheckBox
        ImportExcelSheetButton          matlab.ui.control.Button
        EditField_3                     matlab.ui.control.EditField
        CustomNameforAvgScoresLabel     matlab.ui.control.Label
        BusyLamp                        matlab.ui.control.Lamp
        ScoreTypeEditFieldLabel         matlab.ui.control.Label
        ScoreTypeEditField              matlab.ui.control.EditField
        CALCULATEAVERAGESCORESCheckBox  matlab.ui.control.CheckBox
        ADDNEWVARIABLEButton            matlab.ui.control.Button
        UPDRSTab                        matlab.ui.container.Tab
        GENERATEButton                  matlab.ui.control.Button
        PostopColumnStartEditFieldLabel  matlab.ui.control.Label
        CheckBox_3                      matlab.ui.control.CheckBox
        CheckBox_2                      matlab.ui.control.CheckBox
        BASELINEEditField               matlab.ui.control.EditField
        POSTOPEditField                 matlab.ui.control.EditField
        CheckBox                        matlab.ui.control.CheckBox
        EditField                       matlab.ui.control.EditField
        EditField_2                     matlab.ui.control.EditField
        LateralityButtonGroup           matlab.ui.container.ButtonGroup
        BilateralButton                 matlab.ui.control.RadioButton
        RightButton                     matlab.ui.control.RadioButton
        LeftButton                      matlab.ui.control.RadioButton
        ThresholdTremorScoresCheckBox   matlab.ui.control.CheckBox
        MotorScoreTypeDropDownLabel     matlab.ui.control.Label
        MotorScoreTypeDropDown          matlab.ui.control.DropDown
        BASELINESTARTDropDown_2Label    matlab.ui.control.Label
        BASELINESTARTDropDown_2         matlab.ui.control.DropDown
        BASELINESTOPDropDown_2Label     matlab.ui.control.Label
        BASELINESTOPDropDown_2          matlab.ui.control.DropDown
        BASELINESTARTDropDown_2Label_2  matlab.ui.control.Label
        BASELINESTARTDropDown_3         matlab.ui.control.DropDown
        BASELINESTOPDropDown_2Label_2   matlab.ui.control.Label
        BASELINESTOPDropDown_3          matlab.ui.control.DropDown
        BASELINESTARTDropDown_2Label_3  matlab.ui.control.Label
        BASELINESTARTDropDown_4         matlab.ui.control.DropDown
        BASELINESTOPDropDown_2Label_3   matlab.ui.control.Label
        BASELINESTOPDropDown_4          matlab.ui.control.DropDown
        BASELINESTARTDropDown_2Label_4  matlab.ui.control.Label
        BASELINESTARTDropDown_5         matlab.ui.control.DropDown
        BASELINESTOPDropDown_2Label_4   matlab.ui.control.Label
        BASELINESTOPDropDown_5          matlab.ui.control.DropDown
        ImportExcelSheetButton_2        matlab.ui.control.Button
    end

  properties (Access = public)
        
        postop_checked_box
        medon_stimoff
        medon_stimon
        lead_file
        ex_excel
        fname       
        baseline
        baseline_2
        postop
        postop_2
        baseline_checked_box
        postop2_checked_box
        tdata
        baseline_start_medoff
        baseline_stop_medoff
        baseline_start_medon
        baseline_stop_medon
        postop_start_medoff
        postop_stop_medoff
        postop_start_medon
        postop_stop_medon
        BASET
        POSTT
        patients
        flag
        global_score
        brady
        rigidity
        axial
        tremor
        postop_start
        postop_stop
        hndl
        GLOBAL_ABS_VALS
        GLOBAL_IMP_VALS
        GLOBAL_CLEANED_VALS
        BRADYKINESIA_ABS_VALS
        BRADYKINESIA_IMP_VALS
        BRADYKINESIA_CLEANED_VALS
        RIGIDITY_ABS_VALS
        RIGIDITY_CLEANED_VALS
        RIGIDITY_IMP_VALS
        AXIAL_ABS_VALS
        AXIAL_CLEANED_VALS
        AXIAL_IMP_VALS
        TREMOR_ABS_VALS
        TREMOR_CLEANED_VALS
        TREMOR_IMP_VALS
        CUSTOM_ABS_VALS
        CUSTOM_CLEANED_VALS
        CUSTOM_IMP_VALS
        somatotopy
        bl_start
        bl_stop
        pp_start
        pp_stop
        var_name
        uil
        uil2
        another_lbl
        another_lbl2
    end
    
    
    methods (Access = private)
        
        function score_generator(app,postopid,postop_range,selection)            
            app.somatotopy = app.LateralityButtonGroup.SelectedObject;
            baseline_range = [1,app.baseline_start_medoff,length(app.patients)+1,app.baseline_stop_medoff];
            baseline_tab = readtable(app.fname,'Range',baseline_range);
            baseline_mat = readmatrix(app.fname,'Range',baseline_range);
            baseline_tab_names = baseline_tab.Properties.VariableNames;
            
            for i=1:length(postop_range)
                postop_tab{i,1} = readtable(app.fname,'Range',postop_range{i,1});
                postop_mat{i,1} = readmatrix(app.fname,'Range',postop_range{i,1});
                postop_tab_names{i,1} = postop_tab{i,1}.Properties.VariableNames;
                if all(isnan(postop_mat{i,:}(1,:)))
                   postop_mat{i,:}(1,:) = [];
                end
            end
            if all(isnan(baseline_mat(1,:)))
                baseline_mat(1,:) = [];
            end

            for pt=1:length(app.patients)
                odir=[app.lead_file.patient.list{pt},'/clinical/'];
                if strcmp(selection,'Add to Scores Directory')
                   load([app.lead_file.patient.list{pt},'/clinical/','clinical_scores.mat'])
                end
                if ~exist(odir,'dir')
                    mkdir(odir);
                end
                for i=1:length(postopid)
                    app.BASET =array2table(baseline_mat,'VariableNames',baseline_tab_names);
                    app.POSTT =array2table(postop_mat{i,:},'VariableNames',postop_tab_names{i,:});
                    BASET_pt = app.BASET(pt,:);
                    POSTT_pt = app.POSTT(pt,:);
                    identifier = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'MDS*'));
                    motor_identifier = BASET_pt(:,BASET_pt.Properties.VariableNames(identifier));
                    if strcmp(app.MotorScoreTypeDropDown.Value,'UPDRS')
                        motor_flag = 'Motor_UPDRS';
                    elseif strcmp(app.MotorScoreTypeDropDown.Value,'MDS')
                        motor_flag = 'Motor_MDSUPDRS';
                    else
                        if isnan(motor_identifier{1,1})
                            motor_flag = 'Motor_UPDRS';
                        else
                            motor_flag = 'Motor_MDSUPDRS';
                        end
                        
                    end
                    postop_flag = postopid{i,:};
                    scores.(motor_flag).(postop_flag).Global.(app.baseline) = BASET_pt;
                    scores.(motor_flag).(postop_flag).Global.(postop_flag) = POSTT_pt;
                    total_available = regexpi(BASET_pt.Properties.VariableNames{end}, '.*Total|Total.*');
                    if isempty(total_available) || all(BASET_pt{:,end}==0) || all(BASET_pt{:,end}=='NaN')
                        %if you have to calculate the total, add up
                        %already total names do not 
                        %consider column names which are "total". MAYBE
                        %THIS CAN BE CHANGED?
                        exclude_preop = cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, '.*Total|Total.*'));
                        exclude_postop = cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, '.*Total|Total.*'));
                        global_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(exclude_preop));
                        global_postop = POSTT_pt(:,POSTT_pt.Properties.VariableNames(exclude_postop));
                        global_preop.Total = nansum(global_preop{:,1:end},2);
                        global_postop.Total = nansum(global_postop{:,1:end},2);
                        scores.(motor_flag).(postop_flag).Global.(app.baseline) = global_preop;
                        scores.(motor_flag).(postop_flag).Global.(postop_flag) = global_postop;
                        scores.(motor_flag).(postop_flag).Global.abs_improvements = global_preop.Total - global_postop.Total;
                        scores.(motor_flag).(postop_flag).Global.perc_improvements = (global_preop.Total - global_postop.Total)./global_preop.Total;
                    else
                        scores.(motor_flag).(postop_flag).Global.abs_improvements = BASET_pt{:,end} - POSTT_pt{:,end};
                        scores.(motor_flag).(postop_flag).Global.perc_improvements = ((BASET_pt{:,end} - POSTT_pt{:,end})./BASET_pt{:,end});
                    end
                    app.GLOBAL_ABS_VALS.(postop_flag)(pt,:) = scores.(motor_flag).(postop_flag).Global.abs_improvements;
                    app.GLOBAL_IMP_VALS.(postop_flag)(pt,:) = scores.(motor_flag).(postop_flag).Global.perc_improvements;
                    GLOBAL_BASELINE.(postop_flag)(pt,:) = BASET_pt{:,end};
                    if strcmp(motor_flag,'Motor_UPDRS')
                        if strcmp(app.somatotopy.Text,'Left')
                            hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'U.*[2][3-6].*Left|U.*31'));
                            hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'U.*[2][3-6].*Left|U.*31'));
                        elseif strcmp(app.somatotopy.Text,'Right')
                            hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'U.*[2][3-6].*Right|U.*31'));
                            hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'U.*[2][3-6].*Right|U.*31'));
                        else
                            hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'U.*[2][3-6]|U.*31'));
                            hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'U.*[2][3-6]|U.*31'));
                        end
                    else
                        if strcmp(app.somatotopy.Text,'Left')
                            hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'U.*[2][3-6].*Left|U.*31|ToeTappingL'));
                            hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'U.*[2][3-6].*Left|U.*31|ToeTappingL'));
                        elseif strcmp(app.somatotopy.Text,'Right')
                            hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'U.*[2][3-6].*Right|U.*31|ToeTappingR'));
                            hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'U.*[2][3-6].*Right|U.*31|ToeTappingR'));
                        else
                            hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'U.*[2][3-6]|U.*31|ToeTapping'));
                            hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'U.*[2][3-6]|U.*31|ToeTapping'));
                        end
                    end
                    
                    brady_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(hasMatch_preop));
                    brady_postop = POSTT_pt(:,POSTT_pt.Properties.VariableNames(hasMatch_postop));
                    brady_preop.Total = nansum(brady_preop{:,1:end},2);
                    brady_postop.Total = nansum(brady_postop{:,1:end},2);
                    scores.(motor_flag).(postop_flag).Bradykinesia.(app.baseline) = brady_preop;
                    scores.(motor_flag).(postop_flag).Bradykinesia.(postop_flag) = brady_postop;
                    scores.(motor_flag).(postop_flag).Bradykinesia.abs_improvements = brady_preop.Total - brady_postop.Total;
                    scores.(motor_flag).(postop_flag).Bradykinesia.perc_improvements = ((brady_preop.Total-brady_postop.Total)./brady_preop.Total);
                    app.BRADYKINESIA_ABS_VALS.(postop_flag)(pt,:) = scores.(motor_flag).(postop_flag).Bradykinesia.abs_improvements;
                    app.BRADYKINESIA_IMP_VALS.(postop_flag)(pt,:) = scores.(motor_flag).(postop_flag).Bradykinesia.perc_improvements;
                    BRADYKINESIA_BASELINE.(postop_flag)(pt,:) = brady_preop{:,end};
                    
                    
                    if strcmp(app.somatotopy.Text,'Left')
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'U.*22.*Left'));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'U.*22.*Left'));
                    elseif strcmp(app.somatotopy.Text,'Right')
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'U.*22.*Right'));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'U.*22.*Right'));
                    else
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'U.*22'));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'U.*22'));
                    end
                    rigidity_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(hasMatch_preop));
                    rigidity_postop = POSTT_pt(:,POSTT_pt.Properties.VariableNames(hasMatch_postop));
                    rigidity_preop.Total = nansum(rigidity_preop{:,1:end},2);
                    rigidity_postop.Total = nansum(rigidity_postop{:,1:end},2);
                    scores.(motor_flag).(postop_flag).Rigidity.(app.baseline) = rigidity_preop;
                    scores.(motor_flag).(postop_flag).Rigidity.(postop_flag) = rigidity_postop;
                    scores.(motor_flag).(postop_flag).Rigidity.abs_improvements = rigidity_preop.Total - rigidity_postop.Total;
                    scores.(motor_flag).(postop_flag).Rigidity.perc_improvements = ((rigidity_preop.Total-rigidity_postop.Total)./rigidity_preop.Total);
                    app.RIGIDITY_ABS_VALS.(postop_flag)(pt,:) = scores.(motor_flag).(postop_flag).Rigidity.abs_improvements;
                    app.RIGIDITY_IMP_VALS.(postop_flag)(pt,:) = scores.(motor_flag).(postop_flag).Rigidity.perc_improvements;
                    RIGIDITY_BASELINE.(postop_flag)(pt,:) = rigidity_preop{:,end};
                    
                    
                    if strcmp(motor_flag,'Motor_MDSUPDRS')
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'U.*2[7-9]|U.*30|MDSFOG'));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'U.*2[7-9]|U.*30|MDSFOG'));
                    else
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'U.*2[7-9]|U.*30'));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'U.*2[7-9]|U.*30'));
                    end
                    axial_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(hasMatch_preop));
                    axial_postop = POSTT_pt(:,POSTT_pt.Properties.VariableNames(hasMatch_postop));
                    axial_preop.Total = nansum(axial_preop{:,1:end},2);
                    axial_postop.Total = nansum(axial_postop{:,1:end},2);
                    scores.(motor_flag).(postop_flag).Axial.(app.baseline) = axial_preop;
                    scores.(motor_flag).(postop_flag).Axial.(postop_flag) = axial_postop;
                    scores.(motor_flag).(postop_flag).Axial.abs_improvements = axial_preop.Total - axial_postop.Total;
                    scores.(motor_flag).(postop_flag).Axial.perc_improvements = ((axial_preop.Total-axial_postop.Total)./axial_preop.Total);
                    app.AXIAL_ABS_VALS.(postop_flag)(pt,:) = scores.(motor_flag).(postop_flag).Axial.abs_improvements;
                    app.AXIAL_IMP_VALS.(postop_flag)(pt,:) = scores.(motor_flag).(postop_flag).Axial.perc_improvements;
                    AXIAL_BASELINE.(postop_flag)(pt,:) = axial_preop{:,end};
                                         
                    if strcmp(app.somatotopy.Text,'Left')
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'tremor.*Left'));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'tremor.*Left'));
                    elseif strcmp(app.somatotopy.Text,'Right')
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'tremor.*Right'));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'tremor.*Right'));
                    else
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'tremor'));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'tremor'));
                    end
                    tremor_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(hasMatch_preop));
                    tremor_postop = POSTT_pt(:,POSTT_pt.Properties.VariableNames(hasMatch_postop));
                    tremor_preop.Total = nansum(tremor_preop{:,1:end},2);
                    tremor_postop.Total = nansum(tremor_postop{:,1:end},2);
                    scores.(motor_flag).(postop_flag).Tremor.(app.baseline) = tremor_preop;
                    scores.(motor_flag).(postop_flag).Tremor.(postop_flag) = tremor_postop;
                    if app.ThresholdTremorScoresCheckBox.Value
                        if tremor_preop.Total < 2
                           scores.(motor_flag).(postop_flag).Tremor.abs_improvements = NaN; 
                           scores.(motor_flag).(postop_flag).Tremor.perc_improvements = NaN;
                        else
                           scores.(motor_flag).(postop_flag).Tremor.abs_improvements = tremor_preop.Total - tremor_postop.Total;
                           scores.(motor_flag).(postop_flag).Tremor.perc_improvements = ((tremor_preop.Total-tremor_postop.Total)./tremor_preop.Total);
                        end
                    else
                      scores.(motor_flag).(postop_flag).Tremor.abs_improvements = tremor_preop.Total - tremor_postop.Total;
                      scores.(motor_flag).(postop_flag).Tremor.perc_improvements = ((tremor_preop.Total-tremor_postop.Total)./tremor_preop.Total);  
                    end
                    app.TREMOR_ABS_VALS.(postop_flag)(pt,:) = scores.(motor_flag).(postop_flag).Tremor.abs_improvements;
                    app.TREMOR_IMP_VALS.(postop_flag)(pt,:) = scores.(motor_flag).(postop_flag).Tremor.perc_improvements;
                    TREMOR_BASELINE.(postop_flag)(pt,:) = tremor_preop{:,end};

                end
                save([odir,'clinical_scores.mat'],'scores');
                clear scores;
            end
            for i=1:length(postopid)
                postop_flag = postopid{i,:};
                app.GLOBAL_CLEANED_VALS.(postop_flag) = ea_resid(GLOBAL_BASELINE.(postop_flag),app.GLOBAL_ABS_VALS.(postop_flag));
                app.BRADYKINESIA_CLEANED_VALS.(postop_flag) = ea_resid(BRADYKINESIA_BASELINE.(postop_flag),app.BRADYKINESIA_ABS_VALS.(postop_flag));
                app.RIGIDITY_CLEANED_VALS.(postop_flag) = ea_resid(RIGIDITY_BASELINE.(postop_flag),app.RIGIDITY_ABS_VALS.(postop_flag));
                app.AXIAL_CLEANED_VALS.(postop_flag) = ea_resid(AXIAL_BASELINE.(postop_flag),app.AXIAL_ABS_VALS.(postop_flag));
                app.TREMOR_CLEANED_VALS.(postop_flag) = ea_resid(TREMOR_BASELINE.(postop_flag),app.TREMOR_ABS_VALS.(postop_flag));
                
            end
            for i = 1:length(app.patients)
                load([app.lead_file.patient.list{i},'/clinical/','clinical_scores.mat']);
                odir = [app.lead_file.patient.list{i},'/clinical/'];
                for j=1:length(postopid)
                    postop_flag = postopid{j,:};
                    if isfield(scores,'Motor_UPDRS')
                        motor_flag = 'Motor_UPDRS';
                    else
                        motor_flag = 'Motor_MDSUPDRS';
                    end
                    scores.(motor_flag).(postop_flag).Global.cleaned_improvements = app.GLOBAL_CLEANED_VALS.(postop_flag)(i,:);
                    scores.(motor_flag).(postop_flag).Bradykinesia.cleaned_improvements = app.BRADYKINESIA_CLEANED_VALS.(postop_flag)(i,:);
                    scores.(motor_flag).(postop_flag).Rigidity.cleaned_improvements = app.RIGIDITY_CLEANED_VALS.(postop_flag)(i,:);
                    scores.(motor_flag).(postop_flag).Axial.cleaned_improvements = app.AXIAL_CLEANED_VALS.(postop_flag)(i,:);
                    scores.(motor_flag).(postop_flag).Tremor.cleaned_improvements = app.TREMOR_CLEANED_VALS.(postop_flag)(i,:);
                end
                save([odir,'clinical_scores.mat'],'scores');
            end
            to_disp = ['*' 'Scores saved successfully for all patients' '*'];
            disp(to_disp)
        end
      
        function baseline_generator(app,postopid,postop_range)
            baseline_mat = readmatrix(app.fname,'Range',postop_range);
            baseline_tab = readtable(app.fname,'Range',postop_range);
            baseline_tab_names = baseline_tab.Properties.VariableNames;
            if all(isnan(baseline_mat(1,:)))
                baseline_mat(1,:) = [];
            end
            
            app.BASET =array2table(baseline_mat,'VariableNames',baseline_tab_names);
            for pt=1:length(app.patients)
                odir=[app.lead_file.patient.list{pt},'/clinical/'];
                if ~exist(odir,'dir')
                    mkdir(odir);
                end
                BASET_pt = app.BASET(pt,:);
                identifier = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'MDS*'));
                motor_identifier = BASET_pt(:,BASET_pt.Properties.VariableNames(identifier));
                
                if isnan(motor_identifier{1,1}) % UPDRS
                    motor_flag = 'Motor_UPDRS';
                else
                    motor_flag = 'Motor_MDSUPDRS';
                end   
                scores.(motor_flag).(postopid).Global.(postopid) = BASET_pt;
                if strcmp(app.somatotopy.Text,'Left')
                    hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'U.*[2][3-6].*Left|U.*31'));
                    
                elseif strcmp(app.somatotopy.Text,'Right')
                    hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'U.*[2][3-6].*Right|U.*31'));
                else
                    hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'U.*[2][3-6]|U.*31'));
                end
                
                brady_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(hasMatch_preop));
                brady_preop.Total = nansum(brady_preop{:,1:end},2);
                scores.(motor_flag).(postopid).Bradykinesia.(postopid) = brady_preop;
                
                if strcmp(app.somatotopy.Text,'Left')
                    hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'U.*22.*Left'));
                elseif strcmp(app.somatotopy.Text,'Right')
                    hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'U.*22.*Right'));
                else
                    hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'U.*22'));
                end
                rigidity_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(hasMatch_preop));
                rigidity_preop.Total = nansum(rigidity_preop{:,1:end},2);
                scores.(motor_flag).(postopid).Rigidity.(postopid) = rigidity_preop;
                
                hasMatch = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'U.*2[7-9]|U.*30'));
                axial_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(hasMatch));
                axial_preop.Total = nansum(axial_preop{:,1:end},2);
                scores.(motor_flag).(postopid).Axial.(postopid) = axial_preop;
                
                if strcmp(app.somatotopy.Text,'Left')
                    hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'tremor.*Left'));
                elseif strcmp(app.somatotopy.Text,'Right')
                    
                    hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'tremor.*Right'));
                else
                    hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'tremor'));
                end
                tremor_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(hasMatch_preop));
                tremor_preop.Total = nansum(tremor_preop{:,1:end},2);
                scores.(motor_flag).(postopid).Tremor.(postopid) = tremor_preop;
                save([odir,'clinical_scores.mat'],'scores');
                clear score;
            end
            to_disp = ['*' 'Scores saved successfully for all patients' '*'];
            disp(to_disp);
        end
        
        function column_names = read_tab(app)
            
            alpha1 = 'ABCDEFGHIJKLMNOPQRSTUVWXYZ';
            
            for i=1:length(alpha1)
                column_names{i} = [alpha1(i) ': ' app.tdata.Properties.VariableNames{i}];
            end
            j = 1;
            for i=length(alpha1)+1:2*length(alpha1)
                column_names{i} = [alpha1(1),alpha1(j) ': ' app.tdata.Properties.VariableNames{i}];
                j = j+1;
            end
            j = 1;
            for i=2*length(alpha1)+1:length(app.tdata.Properties.VariableNames)
                column_names{i} = [alpha1(2),alpha1(j) ': ' app.tdata.Properties.VariableNames{i}];
                j = j+1;
            end           
        end
        function custom_score_gen(app,baseline_range,postop_range,flag,selection)
            
            baseline_mat = readmatrix(app.fname,'Range',baseline_range);
            baseline_tab = readtable(app.fname,'Range',baseline_range);
            baseline_tab_names = baseline_tab.Properties.VariableNames;
            if all(isnan(baseline_mat(1,:)))
                baseline_mat(1,:) = [];
            end
            BASET =array2table(baseline_mat,'VariableNames',baseline_tab_names);
            if ~isempty(postop_range)
                %this would only happen if you have selected the change scores
                % & average scores OR if you have selected ONLY the average
                % scores option. (flag 2 & flag 3)
                postop_mat = readmatrix(app.fname,'Range',postop_range);
                postop_tab = readtable(app.fname,'Range',postop_range);
                postop_tab_names = postop_tab.Properties.VariableNames;
                if all(isnan(postop_mat(1,:)))
                    postop_mat(1,:) = [];
                end
                POSTT =array2table(postop_mat,'VariableNames',postop_tab_names);
            end
            for pt = 1:length(app.patients)
                odir = ([app.lead_file.patient.list{pt},'/clinical/']);
                if ~exist(odir,'dir')
                    ea_mkdir(odir)
                end
                if exist([app.lead_file.patient.list{pt},'/clinical/','clinical_scores.mat'],'file')
                    if strcmp(selection,'Add to Scores Directory')
                        load([app.lead_file.patient.list{pt},'/clinical/','clinical_scores.mat'])
                    end
                end
                
                BASET_pt = BASET(pt,:);
                if ~isempty(postop_range)
                    POSTT_pt = POSTT(pt,:);
                end
                motor_flag = app.ScoreTypeEditField.Value;
                postop_flag = app.POSTOPEditField_5.Value;
                if flag == 1
                    scores.(motor_flag).(postop_flag).GLOBAL.([postop_flag '_baseline']) = BASET_pt;
                    scores.(motor_flag).(postop_flag).GLOBAL.([postop_flag '_postop']) = POSTT_pt;
                    scores.(motor_flag).(postop_flag).GLOBAL.abs_improvements = BASET_pt{:,end} - POSTT_pt{:,end};
                    scores.(motor_flag).(postop_flag).GLOBAL.perc_improvements = ((BASET_pt{:,end} - POSTT_pt{:,end})./BASET_pt{:,end});
                    UPDRS_BASELINE(pt,:) = BASET_pt{:,end};
                    UPDRS_ABS_VALUE(pt,:) = scores.(motor_flag).(postop_flag).GLOBAL.abs_improvements;
                else
                    for i=1:size(BASET_pt,2)
                        scores.(motor_flag).(postop_flag).(baseline_tab_names{i}).absolute_values = baseline_mat(pt,i);
                    end
                    if ~isempty(postop_range)
                        for i=1:size(POSTT_pt,2)
                            scores.(motor_flag).(postop_flag).(['Postop' postop_tab_names{i}]).absolute_values = postop_mat(pt,i);
                        end
                    end
                    for i=1:length(baseline_tab_names)
                        baseline_val(i) = (scores.(motor_flag).(postop_flag).(baseline_tab_names{i}).absolute_values);
                        if ~isempty(postop_range)
                            postop_val(i) = (scores.(motor_flag).(postop_flag).(['Postop' postop_tab_names{i}]).absolute_values);
                        end
                    end
                    scores.(motor_flag).(postop_flag).(['Baseline' app.EditField_3.Value]).average_values = mean(baseline_val,'omitnan');
                    if ~isempty(postop_range)
                        scores.(motor_flag).(postop_flag).(['Postop' app.EditField_3.Value]).average_values = mean(postop_val,'omitnan');
                    end
                end
                if flag == 3
                    total_available = regexpi(BASET_pt.Properties.VariableNames{end}, '.*Total|Total.*');
                    if isempty(total_available) || all(BASET_pt{:,end}==0) || all(BASET_pt{:,end}=='NaN')
                        exclude_preop = cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, '.*Total|Total.*'));
                        exclude_postop = cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, '.*Total|Total.*'));
                        global_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(exclude_preop));
                        global_postop = POSTT_pt(:,POSTT_pt.Properties.VariableNames(exclude_postop));
                        global_preop.Total = nansum(global_preop{:,1:end},2);
                        global_postop.Total = nansum(global_postop{:,1:end},2);
                        scores.(motor_flag).(postop_flag).GLOBAL.abs_improvements = global_preop.Total - global_postop.Total;
                        scores.(motor_flag).(postop_flag).GLOBAL.perc_improvements = (global_preop.Total - global_postop.Total)./global_preop.Total;
                        UPDRS_BASELINE(pt,:) = global_preop.Total;
                        UPDRS_ABS_VALUE(pt,:) = scores.(motor_flag).(postop_flag).GLOBAL.abs_improvements;
                    else
                        scores.(motor_flag).(postop_flag).GLOBAL.abs_improvements = BASET_pt{:,end} - POSTT_pt{:,end};
                        scores.(motor_flag).(postop_flag).GLOBAL.perc_improvements = ((BASET_pt{:,end} - POSTT_pt{:,end})./BASET_pt{:,end});
                        UPDRS_BASELINE(pt,:) = BASET_pt{:,end};
                        UPDRS_ABS_VALUE(pt,:) = scores.(motor_flag).(postop_flag).GLOBAL.abs_improvements;
                        
                    end
                    if ~isempty(postop_range)
                        total_available = regexpi(BASET_pt.Properties.VariableNames{end}, '.*Total|Total.*');
                        if isempty(total_available) || all(BASET_pt{:,end}==0) || all(BASET_pt{:,end}=='NaN')
                            exclude_preop = cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, '.*Total|Total.*'));
                            exclude_postop = cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, '.*Total|Total.*'));
                            global_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(exclude_preop));
                            global_postop = POSTT_pt(:,POSTT_pt.Properties.VariableNames(exclude_postop));
                            global_preop.Total = nansum(global_preop{:,1:end},2);
                            global_postop.Total = nansum(global_postop{:,1:end},2);
                            scores.(motor_flag).(postop_flag).GLOBAL.abs_improvements = global_preop.Total - global_postop.Total;
                            scores.(motor_flag).(postop_flag).GLOBAL.perc_improvements = (global_preop.Total - global_postop.Total)./global_preop.Total;
                            UPDRS_BASELINE(pt,:) = global_preop.Total;
                            UPDRS_ABS_VALUE(pt,:) = scores.(motor_flag).(postop_flag).GLOBAL.abs_improvements;
                        else
                            scores.(motor_flag).(postop_flag).GLOBAL.abs_improvements = BASET_pt{:,end} - POSTT_pt{:,end};
                            scores.(motor_flag).(postop_flag).GLOBAL.perc_improvements = ((BASET_pt{:,end} - POSTT_pt{:,end})./BASET_pt{:,end});
                            UPDRS_BASELINE(pt,:) = BASET_pt{:,end};
                            UPDRS_ABS_VALUE(pt,:) = scores.(motor_flag).(postop_flag).GLOBAL.abs_improvements;
                        end
                    end
                end
                save([odir,'clinical_scores.mat'],'scores'); 
            end
            if flag == 1 || flag == 3
                UPDRS_cleaned_imprv = ea_resid(UPDRS_BASELINE,UPDRS_ABS_VALUE);
                for pt = 1:length(app.patients)
                    odir = ([app.lead_file.patient.list{pt},'/clinical/']);
                    load([app.lead_file.patient.list{pt},'/clinical/','clinical_scores.mat'])
                    scores.(motor_flag).(postop_flag).GLOBAL.cleaned_improvements = UPDRS_cleaned_imprv(pt,:);
                    save([odir,'clinical_scores.mat'],'scores')
                end
            end
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, excel_sheet_pre, lead_group, handles)
            if ~strcmp(excel_sheet_pre,'')
               app.fname = excel_sheet_pre; 
               app.tdata = readtable(app.fname);
            end
            app.lead_file = lead_group;
            app.BASELINESTARTDropDown_3.Enable = 'off';
            app.BASELINESTOPDropDown_3.Enable = 'off';
            app.BASELINESTARTDropDown_4.Enable = 'off';
            app.BASELINESTOPDropDown_4.Enable = 'off';
            app.BASELINESTARTDropDown_5.Enable = 'off';
            app.BASELINESTOPDropDown_5.Enable = 'off';
            app.hndl = handles;
            app.uil = uilabel(app.CUSTOMTab,'Position',[40,212,108,22]);
            app.uil.FontSize = 12;
            app.uil.FontName = 'Helvetica';
            app.uil2 = uilabel(app.CUSTOMTab,'Position',[300,212,108,22]);
            app.uil2.FontSize = 12;
            app.uil2.FontName = 'Helvetica';
            app.uil.Text = 'BASELINE SCORES';
            app.uil2.Text = 'BASELINE SCORES';
            app.another_lbl = uilabel(app.CUSTOMTab,'Position',[40,180,106,22]);
            app.another_lbl.FontSize = 12;
            app.another_lbl.FontName = 'Helvetica';
            app.another_lbl2 = uilabel(app.CUSTOMTab,'Position',[300,180,106,22]);
            app.another_lbl2.FontSize = 12;
            app.another_lbl2.FontName = 'Helvetica';
            app.another_lbl.Text = 'POSTOP SCORES';
            app.another_lbl2.Text = 'POSTOP SCORES';
        end

        % Callback function
        function MedoffStimoffCheckBoxValueChanged(app, event)
            app.medoff_stimoff = app.MedoffStimoffCheckBox.Value;
            
        end

        % Callback function
        function BaselineColumnStartEditFieldValueChanged(app, event)
            app.baseline_start_medoff = app.BaselineColumnStartEditField.Value;
            
        end

        % Callback function
        function BaselineColumnStopEditFieldValueChanged(app, event)
             app.baseline_stop_medoff = app.BaselineColumnStopEditField.Value;
            
        end

        % Callback function
        function MedoffStimonCheckBoxValueChanged(app, event)
            app.medoff_stimon = app.MedoffStimonCheckBox.Value;
            if app.medoff_stimon
               app.PostopColumnStartEditField.Enable = 'on';
               app.PostopColumnStopEditField.Enable = 'on'; 
            else
               app.PostopColumnStartEditField.Enable = 'off';
               app.PostopColumnStopEditField.Enable = 'off';  
               
            end
            
        end

        % Callback function
        function PostopColumnStartEditFieldValueChanged(app, event)
            app.postop_start_medoff = app.PostopColumnStartEditField.Value;
            
        end

        % Callback function
        function PostopColumnStopEditFieldValueChanged(app, event)
            app.postop_stop_medoff = app.PostopColumnStopEditField.Value;
            
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            app.baseline_checked_box = app.CheckBox_2.Value;
            if app.baseline_checked_box
                app.BASELINESTARTDropDown_4.Enable = 'on';
                app.BASELINESTOPDropDown_4.Enable = 'on';
                
            else
               app.BASELINESTARTDropDown_4.Enable = 'off';
               app.BASELINESTOPDropDown_4.Enable = 'off';
            end
        end

        % Callback function
        function BaselineColumnStartEditField_2ValueChanged(app, event)
            app.baseline_start_medon = app.BaselineColumnStartEditField_2.Value;
             
        end

        % Callback function
        function BaselineColumnStopEditField_2ValueChanged(app, event)
            app.baseline_stop_medon = app.BaselineColumnStopEditField_2.Value;
            
        end

        % Value changed function: CheckBox_3
        function CheckBox_3ValueChanged(app, event)
            app.postop2_checked_box = app.CheckBox_3.Value;
            if app.CheckBox_3.Value
                app.BASELINESTARTDropDown_5.Enable = 'on';
                app.BASELINESTOPDropDown_5.Enable = 'on';
                
            else
               app.BASELINESTARTDropDown_5.Enable = 'off';
               app.BASELINESTOPDropDown_5.Enable = 'off';
            end
        end

        % Callback function
        function PostopColumnStartEditField_2ValueChanged(app, event)
            app.postop_start_medon = app.PostopColumnStartEditField_2.Value;
            
        end

        % Callback function
        function PostopColumnStopEditField_2ValueChanged(app, event)
            app.postop_stop_medon = app.PostopColumnStopEditField_2.Value;
            
        end

        % Callback function
        function SCORESButtonGroupSelectionChanged(app, event)
            %selectedButton = app.SCORESButtonGroup.SelectedObject;
            %if strcmp(selectedButton,'GLOBAL')
            %    disp("global")
            %end
        end

        % Button pushed function: GENERATEButton
        function GENERATEButtonPushed(app, event)
            f = waitbar(0,'Generating Scores..');
            app.BusyLamp.Color = 'red';
            app.baseline = app.BASELINEEditField.Value;
            app.postop = app.POSTOPEditField.Value;
            app.baseline_2 = app.EditField.Value;
            app.postop_2 = app.EditField_2.Value;
            app.somatotopy = app.LateralityButtonGroup.SelectedObject;
            app.patients = app.lead_file.patient.list;
            
            
            if ~app.baseline
                disp("Please provide atleast the baseline scores...")
            end
            already_done = 0;
            pt_cell = table2cell(app.tdata(:,1));
            for i=1:length(app.patients)
                if exist([app.lead_file.patient.list{i},'/clinical/','clinical_scores.mat'],'file');
                  already_done = already_done + 1; %if you have it in the first patient's folder, assume it's there in all patients??
                end
                to_match_pt = [app.lead_file.root pt_cell{i,1}];
                if ~strcmp(app.lead_file.patient.list{i},to_match_pt)
                    disp(['WARNING:' to_match_pt ' is not the same as the patient in the lead group file!'])
                    disp("Continuing as per lead group")
                end
            end
            if already_done == length(app.patients)
               fig = uifigure;
               msg = 'Saving these changes will overwrite previous changes.';
               title = 'Confirm Save';
               selection = uiconfirm(fig,msg,title,...
                   'Options',{'Overwrite','Add to Scores Directory','Cancel'},...
                   'DefaultOption',2,'CancelOption',3);
               close(fig)
           else
               selection = 'Overwrite';
            end
            
            waitbar(0.5,f,'Generating Scores..');
            if app.BASELINESTARTDropDown_2.Enable && ~app.BASELINESTARTDropDown_3.Enable && ~app.BASELINESTARTDropDown_4.Enable && ~app.BASELINESTARTDropDown_5.Enable 
                postopid = app.baseline;
                if ischar(app.baseline_start_medoff) && ischar(app.baseline_stop_medoff)
                   postop_range = [app.baseline_start_medoff ':' app.baseline_stop_medoff]; 
                else
                   postop_range = [1,app.baseline_start_medoff,length(app.patients)+1,app.baseline_stop_medoff];
                end
                baseline_generator(app,'',postopid,postop_range,selection) 
            end
            
            if  app.BASELINESTARTDropDown_3.Enable && ~app.BASELINESTARTDropDown_4.Enable && ~app.BASELINESTARTDropDown_5.Enable
                tic
                postopid{1,:} = app.postop;
                postop_range{1,:} = [1,app.postop_start_medoff,length(app.patients)+1,app.postop_stop_medoff];
                disp("Generating Scores...")
                score_generator(app,postopid,postop_range,selection);
                disp("Process Done **")
                toc
            end
            
            if app.BASELINESTARTDropDown_4.Enable && ~app.BASELINESTARTDropDown_3.Enable && ~app.BASELINESTARTDropDown_5.Enable
                tic
                postopid{1,:} = app.baseline_2;
                if ischar(app.baseline_start_medon) && ischar(app.baseline_stop_medon)
                    postop_range{1,:} = [app.baseline_start_medon ':' app.baseline_stop_medon];
                else
                    postop_range{1,:} = [1,app.baseline_start_medon,length(app.patients)+1,app.baseline_stop_medon];
                end
                disp("Generating Scores...")
                score_generator(app,postopid,postop_range,selection);
                disp("Process Done **")
                toc
            end
            if app.BASELINESTARTDropDown_5.Enable && ~app.BASELINESTARTDropDown_3.Enable && ~app.BASELINESTARTDropDown_4.Enable
                tic
                postopid{1,:} = app.postop_2;
                postop_range{1,:} = [1,app.postop_start_medon,length(app.patients)+1,app.postop_stop_medon];
                disp("Generating Scores...")
                score_generator(app,postopid,postop_range,selection);
                disp("Process Done **")
                toc
            end
            
            if app.BASELINESTARTDropDown_3.Enable && app.BASELINESTARTDropDown_4.Enable && ~app.BASELINESTARTDropDown_5.Enable
               tic
               postopid{1,:} = app.postop;
               postopid{2,:} = app.baseline_2;
               postop_range{1,:} = [1,app.postop_start_medoff,length(app.patients)+1,app.postop_stop_medoff];
               postop_range{2,:} = [1,app.baseline_start_medon,length(app.patients)+1,app.baseline_stop_medon];
               disp("Generating scores...")
               score_generator(app,postopid,postop_range,selection);
               disp("Process Done **")
               toc
           
            end   
            
            if app.BASELINESTARTDropDown_3.Enable && app.BASELINESTARTDropDown_4.Enable && app.BASELINESTARTDropDown_5.Enable
               tic
               postopid{1,:} = app.postop;
               postopid{2,:} = app.baseline_2;
               postopid{3,:} = app.postop_2;
               postop_range{1,:} = [1,app.postop_start_medoff,length(app.patients)+1,app.postop_stop_medoff];
               postop_range{2,:} = [1,app.baseline_start_medon,length(app.patients)+1,app.baseline_stop_medon];
               postop_range{3,:} = [1,app.postop_start_medon,length(app.patients)+1,app.postop_stop_medon];
               disp("Generating Scores...")
               score_generator(app,'',postopid,postop_range,selection);
               disp("Process Done **")
               toc
            end 
            waitbar(1,f,'Scores Generated Successfully!..');
            disp("Process Done **")
            app.lead_file.postopid = postopid;
            setappdata(app.hndl.leadfigure,'M',app.lead_file);
            app.BusyLamp.Color = 'green';
            close(f)
                
        end

        % Callback function
        function GLOBALCheckBoxValueChanged(app, event)
            app.global_score = app.GLOBALCheckBox.Value;
            
        end

        % Callback function
        function AXIALCheckBoxValueChanged(app, event)
            app.axial = app.AXIALCheckBox.Value;
            
        end

        % Callback function
        function RIGIDITYCheckBoxValueChanged(app, event)
            app.rigidity = app.RIGIDITYCheckBox.Value;
            
        end

        % Callback function
        function BRADYKINESIACheckBoxValueChanged(app, event)
            app.brady = app.BRADYKINESIACheckBox.Value;
            
        end

        % Callback function
        function TREMORCheckBoxValueChanged(app, event)
            app.tremor = app.TREMORCheckBox.Value;
            
        end

        % Callback function
        function CREATECUSTOMSUBSCOREButtonPushed(app, event)
           
        end

        % Callback function
        function CreateCustomSubscoreEditFieldValueChanged(app, event)
            %app.create_custom = app.CreateCustomSubscoreEditField.Value;
        end

        % Callback function
        function CreateCustomTextAreaValueChanged(app, event)
            app.create_custom = app.CreateCustomTextArea.Value;
            
        end

        % Callback function
        function OKButtonPushed(app, event)
           
        end

        % Value changed function: BASELINEEditField
        function BASELINEEditFieldValueChanged(app, event)
            app.baseline = app.BASELINEEditField.Value;
            
        end

        % Value changed function: POSTOPEditField
        function POSTOPEditFieldValueChanged(app, event)
            app.postop = app.POSTOPEditField.Value;
            
        end

        % Callback function
        function LEADGROUPIDEditFieldValueChanged(app, event)
            app.leadgroupid = app.LEADGROUPIDEditField.Value;
            
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            app.postop_checked_box = app.CheckBox.Value;
            
            if app.postop_checked_box
               app.BASELINESTARTDropDown_3.Enable = 'on';
               app.BASELINESTOPDropDown_3.Enable = 'on';
            else
               app.BASELINESTARTDropDown_3.Enable = 'off';
               app.BASELINESTOPDropDown_3.Enable = 'off';
               
            end
            
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.baseline_2 = app.EditField.Value;
            
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            app.postop_2 = app.EditField_2.Value;
            
        end

        % Callback function
        function BASELINEEditField_2ValueChanged(app, event)
            
        end

        % Callback function
        function BaselineColumnStartEditField_3ValueChanged(app, event)
            
        end

        % Callback function
        function BaselineColumnStopEditField_3ValueChanged(app, event)
            
        end

        % Callback function
        function CheckBox_4ValueChanged(app, event)
            
        end

        % Callback function
        function CreateCustomTextArea_2ValueChanged(app, event)
           
        end

        % Callback function
        function SomatotopyDropDownValueChanged(app, event)
            %app.somatotopy = app.SomatotopyDropDown.Value;
            
        end

        % Selection changed function: LateralityButtonGroup
        function LateralityButtonGroupSelectionChanged(app, event)
            app.somatotopy = app.LateralityButtonGroup.SelectedObject;
            
        end

        % Callback function
        function ImportfromEXCELButton_2Pushed(app, event)

             
        end

        % Callback function
        function ImportfromEXCELButton_3Pushed(app, event)
          
        end

        % Callback function
        function ImportfromEXCELButton_4Pushed(app, event)
          
        end

        % Callback function
        function PostopColumnStartEditField_3ValueChanged(app, event)
            
        end

        % Callback function
        function PostopColumnStopEditField_3ValueChanged(app, event)
            
        end

        % Callback function
        function PostopColumnStartEditField_5ValueChanged(app, event)
            
        end

        % Callback function
        function PostopColumnStopEditField_5ValueChanged(app, event)
            
        end

        % Callback function
        function PostopColumnStartEditField_6ValueChanged(app, event)
            
        end

        % Callback function
        function PostopColumnStopEditField_6ValueChanged(app, event)
            
        end

        % Callback function
        function GENERATEButton_2Pushed(app, event)
            
        end

        % Callback function
        function POSTOPEditField_2ValueChanged(app, event)
            
        end

        % Callback function
        function POSTOPEditField_4ValueChanged(app, event)
            
        end

        % Callback function
        function ImportfromEXCELButton_5Pushed(app, event)
            
        end

        % Callback function
        function ImportfromEXCELButton_6Pushed(app, event)
          
        end

        % Callback function
        function ImportfromEXCELButton_7Pushed(app, event)
          
        end

        % Callback function
        function ImportfromEXCELButton_8Pushed(app, event)
           
       
        end

        % Callback function
        function ExportasExcelButton_2Pushed(app, event)
            global_abs_table = array2table(app.GLOBAL_ABS_VALS,'VariableNames',{'GLOBAL ABSOLUTE VALUES'});
            global_imp_table = array2table(app.GLOBAL_IMP_VALS,'VariableNames',{'GLOBAL IMPROVEMENT VALUES'});
            global_cleaned_tables = array2table(app.GLOBAL_CLEANED_VALS,'VariableNames',{'GLOBAL CLEANED VALUES'});            
            full_table = [app.BASET,app.POSTT,global_abs_table,global_imp_table,global_cleaned_tables];
            writetable(full_table,uiputfile('cleaned_clinical_scores.xlsx'));
        end

        % Value changed function: ThresholdTremorScoresCheckBox
        function ThresholdTremorScoresCheckBoxValueChanged(app, event)
            value = app.ThresholdTremorScoresCheckBox.Value;
            
        end

        % Value changed function: MotorScoreTypeDropDown
        function MotorScoreTypeDropDownValueChanged(app, event)
            value = app.MotorScoreTypeDropDown.Value;
            
        end

        % Button pushed function: GENERATEButton_3
        function GENERATEButton_3Pushed(app, event)
           
           app.patients = app.lead_file.patient.list;   
           app.BusyLamp.Color = 'red';
           pt_cell = table2cell(app.tdata(:,1));
           %SANITY CHECK
           already_done = 0;
           for i=1:length(app.patients)
               to_match_pt = [app.lead_file.root pt_cell{i,1}];
               if exist([app.lead_file.patient.list{i},'/clinical/','clinical_scores.mat'],'file')
                  already_done = already_done + 1; %if you have it in the first patient's folder, assume it's there in all patients??
               end
               if ~strcmp(app.lead_file.patient.list{i},to_match_pt)
                   disp(['WARNING:' to_match_pt ' is not the same as the patient in the lead group file!'])
                   disp("Continuing as per lead group")
               end
           end
           tic
           f = waitbar(0,'Generating Scores..');
          
           
           if app.CALCULATECHANGESCORESCheckBox.Value && ~app.CALCULATEAVERAGESCORESCheckBox.Value
               app.bl_start = app.bl_start;
               app.bl_stop = app.bl_start;
               app.pp_start = app.pp_stop; 
               app.pp_stop = app.pp_stop;
               postop_range = [1,app.pp_start,length(app.patients)+1,app.pp_stop];
               baseline_range = [1,app.bl_start,length(app.patients)+1,app.bl_stop];
           else
               postop_range = [1,app.pp_start,length(app.patients)+1,app.pp_stop];
               baseline_range = [1,app.bl_start,length(app.patients)+1,app.bl_stop];
           end
           if isempty(app.pp_start) && isempty(app.pp_stop)
               postop_range = [];
           end
           if already_done == length(app.patients)
               fig = uifigure;
               msg = 'Saving these changes will overwrite previous changes.';
               title = 'Confirm Save';
               selection = uiconfirm(fig,msg,title,...
                   'Options',{'Overwrite','Add to Scores Directory','Cancel'},...
                   'DefaultOption',2,'CancelOption',3);
               close(fig)
           else
               selection = 'Overwrite';
           end
           waitbar(0.5,f,'Generating Scores..');
           if app.CALCULATECHANGESCORESCheckBox.Value && ~app.CALCULATEAVERAGESCORESCheckBox.Value
               chng_flag = 1;
               custom_score_gen(app,baseline_range,postop_range,chng_flag,selection);
           waitbar(0.75,f,'Generating Scores..');
           elseif app.CALCULATEAVERAGESCORESCheckBox.Value && ~app.CALCULATECHANGESCORESCheckBox.Value
               chng_flag = 2;
               custom_score_gen(app,baseline_range,postop_range,chng_flag,selection);
           elseif app.CALCULATEAVERAGESCORESCheckBox.Value && app.CALCULATECHANGESCORESCheckBox.Value
               chng_flag = 3;
               custom_score_gen(app,baseline_range,postop_range,chng_flag,selection);
           end
           
           waitbar(1,f,'Scores Generated Successfully!')
           close(f);
           disp("Scores successfully saved")
           app.BusyLamp.Color = 'green';
           toc
        end

        % Value changed function: POSTOPEditField_5
        function POSTOPEditField_5ValueChanged(app, event)
            value = app.POSTOPEditField_5.Value;
            
        end

        % Callback function
        function PostopColumnStartEditField_7ValueChanged(app, event)
            app.bl_start = app.PostopColumnStartEditField_7.Value;
            
        end

        % Callback function
        function PostopColumnStopEditField_7ValueChanged(app, event)
            app.bl_stop = app.PostopColumnStopEditField_7.Value;
            
        end

        % Callback function
        function PostopColumnStartEditField_8ValueChanged(app, event)
            app.pp_start = app.PostopColumnStartEditField_8.Value;
            
        end

        % Callback function
        function PostopColumnStopEditField_8ValueChanged(app, event)
            app.pp_stop = app.PostopColumnStopEditField_8.Value;
            
        end

        % Callback function
        function ImportfromEXCELButton_9Pushed(app, event)
            
        end

        % Callback function
        function ImportfromEXCELButton_10Pushed(app, event)
        
        end

        % Drop down opening function: BASELINESTARTDropDown
        function BASELINESTARTDropDownOpening(app, event)
           column_names = read_tab(app);
           app.BASELINESTARTDropDown.Items = column_names;
           
           app.BASELINESTARTDropDown.ValueChangedFcn = @(dd,event) selection(dd);
           function selection(dd)
                val = app.BASELINESTARTDropDown.Value;
                app.bl_start = find(strcmpi(column_names,val));
           end
           
        end

        % Drop down opening function: BASELINESTOPDropDown
        function BASELINESTOPDropDownOpening(app, event)
           column_names = read_tab(app);
           app.BASELINESTOPDropDown.Items = column_names; 
           app.BASELINESTOPDropDown.ValueChangedFcn = @(dd,event) selection(dd);
         
           function selection(dd)
                val = app.BASELINESTOPDropDown.Value;
                if app.CALCULATECHANGESCORESCheckBox.Value && ~app.CALCULATEAVERAGESCORESCheckBox.Value
                    app.pp_stop = find(strcmpi(column_names,val));
                else
                    app.bl_stop = find(strcmpi(column_names,val));
                end
           end
        end

        % Value changed function: BASELINESTARTDropDown
        function BASELINESTARTDropDownValueChanged(app, event)
            value = app.BASELINESTARTDropDown.Value;
        end

        % Drop down opening function: POSTOPSTARTDropDown
        function POSTOPSTARTDropDownOpening(app, event)
           column_names = read_tab(app);
           app.POSTOPSTARTDropDown.Items = column_names; 
           app.POSTOPSTARTDropDown.ValueChangedFcn = @(dd,event) selection(dd);
         
           function selection(dd)
                val = app.POSTOPSTARTDropDown.Value;
                app.pp_start = find(strcmpi(column_names,val));
           end 
        end

        % Drop down opening function: POSTOPSTOPDropDown
        function POSTOPSTOPDropDownOpening(app, event)
           column_names = read_tab(app);
           app.POSTOPSTOPDropDown.Items = column_names;
           app.POSTOPSTOPDropDown.ValueChangedFcn = @(dd,event) selection(dd);
           
         
           function selection(dd)
                val = app.POSTOPSTOPDropDown.Value;
                app.pp_stop = find(strcmpi(column_names,val));
           end
        end

        % Value changed function: CALCULATECHANGESCORESCheckBox
        function CALCULATECHANGESCORESCheckBoxValueChanged(app, event)
            value = app.CALCULATECHANGESCORESCheckBox.Value;
            
            if app.CALCULATECHANGESCORESCheckBox.Value
               app.POSTOPSTARTDropDown.Enable = 'off';
               app.POSTOPSTOPDropDown.Enable = 'off';
               app.uil.Position = [80,217,96,22];
               app.uil.Text = 'START';
               app.uil2.Position = [350,216,96,22];
               app.uil2.Text = 'STOP';
               app.another_lbl.Enable = 'off';
               app.another_lbl2.Enable = 'off';
            elseif ~app.CALCULATEAVERAGESCORESCheckBox.Value && ~app.CALCULATECHANGESCORESCheckBox.Value
                app.uil.Text = 'BASELINE SCORES';
                app.uil2.Text = 'BASELINE SCORES';
                app.uil.Position = [40,212,108,22];
                app.uil2.Position = [300,212,108,22];
                app.another_lbl.Text = 'POSTOP SCORES';
                app.another_lbl2.Text = 'POSTOP SCORES';
                app.another_lbl.Position = [40,180,108,22];
                app.another_lbl2.Position = [300,180,108,22];
            elseif app.CALCULATEAVERAGESCORESCheckBox.Value && ~app.CALCULATECHANGESCORESCheckBox.Value
               app.another_lbl.Enable = 'on';
               app.another_lbl2.Enable = 'on';
               app.uil.Text = 'BASELINE START';
               app.uil2.Text = 'BASELINE STOP';
               app.uil.Position = [40,212,108,22];
               app.uil2.Position = [300,212,108,22];
               app.another_lbl.Text = 'POSTOP START';
               app.another_lbl2.Text = 'POSTOP STOP';
               app.another_lbl.Position = [40,180,108,22];
               app.another_lbl2.Position = [300,180,108,22];
               app.POSTOPSTARTDropDown.Enable = 'on';
               app.POSTOPSTOPDropDown.Enable = 'on';


            end
            
        end

        % Drop down opening function: BASELINESTARTDropDown_2
        function BASELINESTARTDropDown_2Opening(app, event)
           column_names = read_tab(app);
           app.BASELINESTARTDropDown_2.Items = column_names; 
           app.BASELINESTARTDropDown_2.ValueChangedFcn = @(dd,event) selection(dd);
           
           function selection(dd)
                val = app.BASELINESTARTDropDown_2.Value;
                app.baseline_start_medoff = find(strcmpi(column_names,val)); 
           end
        end

        % Drop down opening function: BASELINESTOPDropDown_2
        function BASELINESTOPDropDown_2Opening(app, event)
           column_names = read_tab(app);
           app.BASELINESTOPDropDown_2.Items = column_names; 
           app.BASELINESTOPDropDown_2.ValueChangedFcn = @(dd,event) selection(dd);
         
           function selection(dd)
                val = app.BASELINESTOPDropDown_2.Value;
                app.baseline_stop_medoff = find(strcmpi(column_names,val)); 
           end
        end

        % Drop down opening function: BASELINESTARTDropDown_3
        function BASELINESTARTDropDown_3Opening(app, event)
           column_names = read_tab(app);
           app.BASELINESTARTDropDown_3.Items = column_names; 
           app.BASELINESTARTDropDown_3.ValueChangedFcn = @(dd,event) selection(dd);
         
           function selection(dd)
                val = app.BASELINESTARTDropDown_3.Value;
                app.postop_start_medoff = find(strcmpi(column_names,val)); 
           end 
        end

        % Drop down opening function: BASELINESTOPDropDown_3
        function BASELINESTOPDropDown_3Opening(app, event)
           column_names = read_tab(app);
           app.BASELINESTOPDropDown_3.Items = column_names; 
           app.BASELINESTOPDropDown_3.ValueChangedFcn = @(dd,event) selection(dd);
         
           function selection(dd)
                val = app.BASELINESTOPDropDown_3.Value;
                app.postop_stop_medoff = find(strcmpi(column_names,val)); 
           end
        end

        % Drop down opening function: BASELINESTARTDropDown_4
        function BASELINESTARTDropDown_4Opening(app, event)
           column_names = read_tab(app);
           app.BASELINESTARTDropDown_4.Items = column_names; 
           app.BASELINESTARTDropDown_4.ValueChangedFcn = @(dd,event) selection(dd);
         
           function selection(dd)
                val = app.BASELINESTARTDropDown_4.Value;
                app.baseline_start_medon = find(strcmpi(column_names,val)); 
           end 
        end

        % Drop down opening function: BASELINESTOPDropDown_4
        function BASELINESTOPDropDown_4Opening(app, event)
           column_names = read_tab(app);
           app.BASELINESTOPDropDown_4.Items = column_names; 
           app.BASELINESTOPDropDown_4.ValueChangedFcn = @(dd,event) selection(dd);
         
           function selection(dd)
                val = app.BASELINESTOPDropDown_4.Value;
                app.baseline_stop_medon = find(strcmpi(column_names,val)); 
           end  
        end

        % Drop down opening function: BASELINESTARTDropDown_5
        function BASELINESTARTDropDown_5Opening(app, event)
           column_names = read_tab(app);
           app.BASELINESTARTDropDown_5.Items = column_names; 
           app.BASELINESTARTDropDown_5.ValueChangedFcn = @(dd,event) selection(dd);
         
           function selection(dd)
                val = app.BASELINESTARTDropDown_5.Value;
                app.postop_start_medon = find(strcmpi(column_names,val)); 
           end 
        end

        % Drop down opening function: BASELINESTOPDropDown_5
        function BASELINESTOPDropDown_5Opening(app, event)
           column_names = read_tab(app);
           app.BASELINESTOPDropDown_5.Items = column_names; 
           app.BASELINESTOPDropDown_5.ValueChangedFcn = @(dd,event) selection(dd);
         
           function selection(dd)
                val = app.BASELINESTOPDropDown_5.Value;
                app.postop_stop_medon = find(strcmpi(column_names,val)); 
           end 
        end

        % Callback function
        function ImportExampleExcelSheetButton_2Pushed(app, event)
            [file,path] = uigetfile('*.xlsx',app.lead_file.root);
            app.ex_excel = fullfile(path,file);
          
        end

        % Callback function
        function GENERATEEXCELButtonPushed(app, event)

            patient_tab{:} = app.lead_file.patient.list;
            example_excel = readtable(app.ex_excel);
            arr = zeros(length(app.lead_file.patient.list),(size(example_excel,2)-1));
            arr_cell = num2cell(arr);
            pre_excel = [patient_tab{1,1} arr_cell];
            alpha1 = char(97:122);
            for i=1:length(example_excel.Properties.VariableNames)
                if i <= length(alpha1)
                    clm_names{i} = [alpha1(i) ':' example_excel.Properties.VariableNames];
                else
                    clm_names{i} = [alpha1(i),alpha1(i) ':' example_excel.Properties.VariableNames];
                end
            end
            excel_tab = array2table(pre_excel,'VariableNames',clm_names);
            [file,path] = writetable(excel_tab,uiputfile('clinical_scores.xlsx'));
            app.fname = fullfile(path,file);
            f = msgbox(['Example excel file save successfully at:' app.fname]);
        end

        % Callback function
        function ImportLeadGroupFileButtonPushed(app, event)
            
        end

        % Button pushed function: ImportExcelSheetButton
        function ImportExcelSheetButtonPushed(app, event)
            app.BusyLamp.Color = 'red';
            [file,path] = uigetfile('*.xlsx',app.lead_file.root);
            app.fname = fullfile(path,file);
            f = msgbox(['Selected File:' app.fname]);
            %app.ImportExcelSheetButton_2.Enable = 'off';
            app.tdata = readtable(app.fname);
            app.BusyLamp.Color = 'green';
        end

        % Callback function
        function SheetNameEditFieldValueChanged(app, event)
          
        end

        % Callback function
        function ImportLeadGroupButtonPushed(app, event)
          
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            
        end

        % Menu selected function: forUPDRSMenu
        function forUPDRSMenuSelected(app, event)
            app.ex_excel = '/Users/nanditharajamani/leaddbs/clinical_score_generator/example_excel.xlsx';
            patient_tab{:} = app.lead_file.patient.list;
            example_excel = readtable(app.ex_excel);
            arr = zeros(length(app.lead_file.patient.list),(size(example_excel,2)-1));
            arr_cell = num2cell(arr);
            pre_excel = [patient_tab{1,1} arr_cell];
%             alpha1 = 'ABCDEFGHIJKLMNOPQRSTUVWXYZ';
%             
%             for i=1:length(alpha1)
%                 clm_names{i} = [alpha1(i) ': ' example_excel.Properties.VariableNames{i}];
%             end
%             j = 1;
%             for i=length(alpha1)+1:2*length(alpha1)
%                 clm_names{i} = [alpha1(1),alpha1(j) ': ' example_excel.Properties.VariableNames{i}];
%                 j = j+1;
%             end
%             j = 1;
%             for i=2*length(alpha1)+1:length(example_excel.Properties.VariableNames)
%                 clm_names{i} = [alpha1(2),alpha1(j) ': ' example_excel.Properties.VariableNames{i}];
%                 j = j+1;
%             end
%             
            excel_tab = array2table(pre_excel,'VariableNames',example_excel.Properties.VariableNames);
            [file,path] = uiputfile('clinical_scores.xlsx');
            writetable(excel_tab,fullfile(path,file));
            %app.fname = fullfile(path,file);
        end

        % Menu selected function: forUPDRSMenu_2
        function forUPDRSMenu_2Selected(app, event)
            fields = fieldnames(app.GLOBAL_ABS_VALS);
            for i=1:length(fields)
                global_abs_table = array2table(app.GLOBAL_ABS_VALS.(fields{i}),'VariableNames',{['Global Absolute Improvements' '_' fields{i}]});
                global_imp_table = array2table(app.GLOBAL_IMP_VALS.(fields{i}),'VariableNames',{['Global Percentage Improvements' '_' fields{i}]});
                global_cleaned_tables = array2table(app.GLOBAL_CLEANED_VALS.(fields{i}),'VariableNames',{['Global Cleaned Improvements' '_' fields{i}]});
                bradykinesia_abs_table = array2table(app.BRADYKINESIA_ABS_VALS.(fields{i}),'VariableNames',{['Bradykinesia Absolute Improvements' '_' fields{i}]});
                bradykinesia_imp_table = array2table(app.BRADYKINESIA_IMP_VALS.(fields{i}),'VariableNames',{['Bradykinesia Percentage Improvements' '_' fields{i}]});
                bradykinesia_cleaned_tables = array2table(app.BRADYKINESIA_CLEANED_VALS.(fields{i}),'VariableNames',{['Bradykinesia Cleaned Improvements' '_' fields{i}]});
                rigidity_abs_table = array2table(app.RIGIDITY_ABS_VALS.(fields{i}),'VariableNames',{['Rigidity Absolute Improvements' '_' fields{i}]});
                rigidity_imp_table = array2table(app.RIGIDITY_IMP_VALS.(fields{i}),'VariableNames',{['Rigidity Percentage Improvements' '_' fields{i}]});
                rigidity_cleaned_tables = array2table(app.RIGIDITY_CLEANED_VALS.(fields{i}),'VariableNames',{['Rigidity Cleaned Improvements' '_' fields{i}]});
                axial_abs_table = array2table(app.AXIAL_ABS_VALS.(fields{i}),'VariableNames',{['Axial Absolute Improvements' '_' fields{i}]});
                axial_imp_table = array2table(app.AXIAL_IMP_VALS.(fields{i}),'VariableNames',{['Axial Percentage Improvements' '_' fields{i}]});
                axial_cleaned_tables = array2table(app.AXIAL_CLEANED_VALS.(fields{i}),'VariableNames',{['Axial Cleaned Improvements' '_' fields{i}]});
                tremor_abs_table = array2table(app.TREMOR_ABS_VALS.(fields{i}),'VariableNames',{['Tremor Absolute Improvements' '_' fields{i}]});
                tremor_imp_table = array2table(app.TREMOR_IMP_VALS.(fields{i}),'VariableNames',{['Tremor Percentage Improvements' '_' fields{i}]});
                tremor_cleaned_tables = array2table(app.TREMOR_CLEANED_VALS.(fields{i}),'VariableNames',{['Tremor Cleaned Improvements' '_' fields{i}]});
                if ~strcmp(app.create_custom,'')
                    custom_abs_table = array2table(app.CUSTOM_ABS_VALS.(fields{i}),'VariableNames',{['Custom Absolute Improvements' '_' fields{i}]});
                    custom_imp_table = array2table(app.CUSTOM_IMP_VALS.(fields{i}),'VariableNames',{['Custom Percentage Improvements' '_' fields{i}]});
                    custom_cleaned_tables = array2table(app.CUSTOM_CLEANED_VALS.(fields{i}),'VariableNames',{['Custom Cleaned Improvements' '_' fields{i}]});
                end
            end
            patient_list = array2table(app.lead_file.patient.list,'VariableNames',{'Patients'});
            full_table = [patient_list,global_abs_table,global_imp_table,global_cleaned_tables];
            if ~strcmp(app.create_custom,'')
                subscores_table = [patient_list,bradykinesia_abs_table,bradykinesia_imp_table,bradykinesia_cleaned_tables,rigidity_abs_table,rigidity_imp_table,rigidity_cleaned_tables,axial_abs_table,axial_imp_table,axial_cleaned_tables,tremor_abs_table,tremor_imp_table,tremor_cleaned_tables,custom_abs_table,custom_imp_table,custom_cleaned_tables];
            else
                subscores_table = [patient_list,bradykinesia_abs_table,bradykinesia_imp_table,bradykinesia_cleaned_tables,rigidity_abs_table,rigidity_imp_table,rigidity_cleaned_tables,axial_abs_table,axial_imp_table,axial_cleaned_tables,tremor_abs_table,tremor_imp_table,tremor_cleaned_tables];
            end
           
            writetable(full_table,uiputfile('cleaned_clinical_scores.xlsx'));
            writetable(subscores_table,uiputfile('cleaned_clinical_subscores.xlsx'));


        end

        % Button pushed function: ImportExcelSheetButton_2
        function ImportExcelSheetButton_2Pushed(app, event)
            [file,path] = uigetfile('*.xlsx');
            app.fname = fullfile(path,file);  
            f = msgbox(['Selected File: ' app.fname]);
            app.tdata = readtable(app.fname);
        end

        % Callback function
        function SheetNameEditField_2ValueChanged(app, event)
           
            
        end

        % Value changed function: ScoreTypeEditField
        function ScoreTypeEditFieldValueChanged(app, event)
            value = app.ScoreTypeEditField.Value;
            
        end

        % Value changed function: CALCULATEAVERAGESCORESCheckBox
        function CALCULATEAVERAGESCORESCheckBoxValueChanged(app, event)
            value = app.CALCULATEAVERAGESCORESCheckBox.Value;
            if app.CALCULATEAVERAGESCORESCheckBox.Value
                app.uil.Text = 'BASELINE START';
                app.uil2.Text = 'BASELINE STOP';
                app.uil.Position = [40,216,100,22];
                app.uil2.Position = [300,216,96,22];
                app.POSTOPSTARTDropDown.Enable = 'on';
                app.POSTOPSTOPDropDown.Enable = 'on';
                if ~isempty(app.another_lbl) && ~isempty(app.another_lbl2)
                    delete(app.another_lbl);
                    delete(app.another_lbl2);
                end
                app.another_lbl = uilabel(app.CUSTOMTab,'Position',[40,180,96,22]);
                app.another_lbl.FontSize = 12;
                app.another_lbl.FontName = 'Helvetica';
                app.another_lbl2 = uilabel(app.CUSTOMTab,'Position',[300,180,96,22]);
                app.another_lbl2.FontSize = 12;
                app.another_lbl2.FontName = 'Helvetica';
                app.another_lbl.Text = 'POSTOP START';
                app.another_lbl2.Text = 'POSTOP STOP';
            else
                app.another_lbl.Enable = 'off';
                app.another_lbl2.Enable = 'off';
                app.POSTOPSTARTDropDown.Enable = 'off';
                app.POSTOPSTOPDropDown.Enable = 'off';
                app.uil.Text = 'START';
                app.uil2.Text = 'STOP';
            end
            
        end

        % Menu selected function: forOtherScoretypesMenu
        function forOtherScoretypesMenuSelected(app, event)
            app.ex_excel = '/Users/nanditharajamani/leaddbs/clinical_score_generator/example_excel.xlsx';
            patient_tab{:} = app.lead_file.patient.list;
            example_excel = readtable(app.ex_excel);
            arr = zeros(length(app.lead_file.patient.list),(size(example_excel,2)-1));
            arr_cell = num2cell(arr);
            pre_excel = [patient_tab{1,1} arr_cell];
            clm_names{1} = 'PatientID';
            for i=2:length(example_excel.Properties.VariableNames)
                clm_names{i} = ['Var' num2str(i)];
            end
            
            excel_tab = array2table(pre_excel,'VariableNames',clm_names);
            [file,path] = uiputfile('clinical_scores.xlsx');
            writetable(excel_tab,fullfile(path,file));
            %app.fname = fullfile(path,file);
        end

        % Button pushed function: ADDNEWVARIABLEButton
        function ADDNEWVARIABLEButtonPushed(app, event)
            f = uifigure;
            table_names = {'Patient ID','Value'};
            patient_tab{:} = app.lead_file.patient.list;
            for i=1:length(patient_tab{1,1})
                new_str = strsplit(patient_tab{1,1}{i,1},'/');
                patient_list{i,:} = new_str{end};
            end
            arr = zeros(length(app.lead_file.patient.list),1);
            arr_cell = num2cell(arr);
            pre_excel = [patient_list arr_cell];
            table_data = array2table(pre_excel,'VariableNames',table_names);
            uit = uitable(f,'Data',table_data);
            uit.ColumnEditable = [false true];
            uit.Position = [200 80 200 300];
            var_field = uieditfield(f,'text','Position',[275 390 90 20],'ValueChangedFcn',@(var_field,event) textChanged(var_field));
            var_lbl = uilabel(f,'Text','Variable Name','Position',[180 375 90 50]);
            
            btn = uibutton(f,'Text','Save','ButtonPushedFcn', @(btn,event) saveButtonPushed(app,btn,uit));
            btn.Position = [275 50 50 20];
            
            function textChanged(var_field)
                app.var_name = var_field.Value;
            end
            function saveButtonPushed(app,btn,uit)
                t = uit.DisplayData;
                for pt=1:length(app.lead_file.patient.list)
                    odir=[app.lead_file.patient.list{pt},'/clinical/'];
                    if exist([app.lead_file.patient.list{pt},'/clinical/','clinical_scores.mat'],'file')
                        load([app.lead_file.patient.list{pt},'/clinical/','clinical_scores.mat'])
                    end
                    scores.(app.ScoreTypeEditField.Value).(app.POSTOPEditField_5.Value).(app.var_name).absolute_values = t.Value{pt};
                    save([odir,'clinical_scores.mat'],'scores');
                    
                end
                close(f)
                
            end

        end

        % Value changed function: POSTOPSTARTDropDown
        function POSTOPSTARTDropDownValueChanged(app, event)
            value = app.POSTOPSTARTDropDown.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.GenerateExcelMenu = uimenu(app.ToolsMenu);
            app.GenerateExcelMenu.Text = 'Generate Excel';
            app.forUPDRSMenu = uimenu(app.GenerateExcelMenu);
            app.forUPDRSMenu.MenuSelectedFcn = createCallbackFcn(app, @forUPDRSMenuSelected, true);
            app.forUPDRSMenu.Text = '..for UPDRS';
            app.forOtherScoretypesMenu = uimenu(app.GenerateExcelMenu);
            app.forOtherScoretypesMenu.MenuSelectedFcn = createCallbackFcn(app, @forOtherScoretypesMenuSelected, true);
            app.forOtherScoretypesMenu.Text = '...for Other Score types';
            app.ExportasExcelMenu = uimenu(app.ToolsMenu);
            app.ExportasExcelMenu.Text = 'Export as Excel';
            app.forUPDRSMenu_2 = uimenu(app.ExportasExcelMenu);
            app.forUPDRSMenu_2.MenuSelectedFcn = createCallbackFcn(app, @forUPDRSMenu_2Selected, true);
            app.forUPDRSMenu_2.Text = '...for UPDRS';
            app.forCustomMenu = uimenu(app.ExportasExcelMenu);
            app.forCustomMenu.Text = '...for Custom';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CUSTOMTab = uitab(app.TabGroup);
            app.CUSTOMTab.Title = 'CUSTOM';
            app.POSTOPEditField_5 = uieditfield(app.CUSTOMTab, 'text');
            app.POSTOPEditField_5.ValueChangedFcn = createCallbackFcn(app, @POSTOPEditField_5ValueChanged, true);
            app.POSTOPEditField_5.Value = 'Custom';
            app.GENERATEButton_3 = uibutton(app.CUSTOMTab, 'push');
            app.GENERATEButton_3.ButtonPushedFcn = createCallbackFcn(app, @GENERATEButton_3Pushed, true);
            app.GENERATEButton_3.Text = 'GENERATE';
            app.BASELINESTARTDropDown = uidropdown(app.CUSTOMTab);
            app.BASELINESTARTDropDown.DropDownOpeningFcn = createCallbackFcn(app, @BASELINESTARTDropDownOpening, true);
            app.BASELINESTARTDropDown.ValueChangedFcn = createCallbackFcn(app, @BASELINESTARTDropDownValueChanged, true);
            app.BASELINESTARTDropDown.Tooltip = {'Select the Column name your baseline score start from. If you have only one score to select, cotinue to choose the same score in both the start and stop baseline columns.'};
            app.BASELINESTOPDropDown = uidropdown(app.CUSTOMTab);
            app.BASELINESTOPDropDown.DropDownOpeningFcn = createCallbackFcn(app, @BASELINESTOPDropDownOpening, true);
            app.BASELINESTOPDropDown.Tooltip = {'Select the Column name your baseline score stop at. If you have only one score to select, cotinue to choose the same score in both the start and stop baseline columns.'};
            app.POSTOPSTARTDropDown = uidropdown(app.CUSTOMTab);
            app.POSTOPSTARTDropDown.DropDownOpeningFcn = createCallbackFcn(app, @POSTOPSTARTDropDownOpening, true);
            app.POSTOPSTARTDropDown.ValueChangedFcn = createCallbackFcn(app, @POSTOPSTARTDropDownValueChanged, true);
            app.POSTOPSTOPDropDown = uidropdown(app.CUSTOMTab);
            app.POSTOPSTOPDropDown.DropDownOpeningFcn = createCallbackFcn(app, @POSTOPSTOPDropDownOpening, true);
            app.POSTOPSTOPDropDown.Tooltip = {'Select the Column name your postop scores stop at. If you have only one score to select, cotinue to choose the same score in both the start and stop baseline columns.'};
            app.CALCULATECHANGESCORESCheckBox = uicheckbox(app.CUSTOMTab);
            app.CALCULATECHANGESCORESCheckBox.ValueChangedFcn = createCallbackFcn(app, @CALCULATECHANGESCORESCheckBoxValueChanged, true);
            app.CALCULATECHANGESCORESCheckBox.Tooltip = {'Check this box to calculate preop-postop improvements. If you have more than one score, the absolute value of each score will also be output.'};
            app.CALCULATECHANGESCORESCheckBox.Text = 'CALCULATE CHANGE SCORES';
            app.ImportExcelSheetButton = uibutton(app.CUSTOMTab, 'push');
            app.ImportExcelSheetButton.ButtonPushedFcn = createCallbackFcn(app, @ImportExcelSheetButtonPushed, true);
            app.ImportExcelSheetButton.Text = 'Import Excel Sheet..';
            app.EditField_3 = uieditfield(app.CUSTOMTab, 'text');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_3.Value = 'Score1';
            app.CustomNameforAvgScoresLabel = uilabel(app.CUSTOMTab);
            app.CustomNameforAvgScoresLabel.Tooltip = {'Please provide a name for the Average scores. This will be reflected as the variable of choice in the lead group GUI.'};
            app.CustomNameforAvgScoresLabel.Text = 'Custom Name for Avg. Scores';
            app.BusyLamp = uilamp(app.CUSTOMTab);
            app.ScoreTypeEditFieldLabel = uilabel(app.CUSTOMTab);
            app.ScoreTypeEditFieldLabel.Text = 'Score Type';
            app.ScoreTypeEditField = uieditfield(app.CUSTOMTab, 'text');
            app.ScoreTypeEditField.ValueChangedFcn = createCallbackFcn(app, @ScoreTypeEditFieldValueChanged, true);
            app.ScoreTypeEditField.Value = 'BDI';
            app.CALCULATEAVERAGESCORESCheckBox = uicheckbox(app.CUSTOMTab);
            app.CALCULATEAVERAGESCORESCheckBox.ValueChangedFcn = createCallbackFcn(app, @CALCULATEAVERAGESCORESCheckBoxValueChanged, true);
            app.CALCULATEAVERAGESCORESCheckBox.Tooltip = {'Calculate the average of the scores you specified. You can specify a custom name for the average scores in the field below'};
            app.CALCULATEAVERAGESCORESCheckBox.Text = 'CALCULATE AVERAGE SCORES';
            app.ADDNEWVARIABLEButton = uibutton(app.CUSTOMTab, 'push');
            app.ADDNEWVARIABLEButton.ButtonPushedFcn = createCallbackFcn(app, @ADDNEWVARIABLEButtonPushed, true);
            app.ADDNEWVARIABLEButton.Text = 'ADD NEW VARIABLE';
            app.UPDRSTab = uitab(app.TabGroup);
            app.UPDRSTab.Title = 'UPDRS';
            app.GENERATEButton = uibutton(app.UPDRSTab, 'push');
            app.GENERATEButton.ButtonPushedFcn = createCallbackFcn(app, @GENERATEButtonPushed, true);
            app.GENERATEButton.Text = 'GENERATE';
            app.PostopColumnStartEditFieldLabel = uilabel(app.UPDRSTab);
            app.PostopColumnStartEditFieldLabel.Text = '';
            app.CheckBox_3 = uicheckbox(app.UPDRSTab);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBox_3ValueChanged, true);
            app.CheckBox_3.Text = '';
            app.CheckBox_2 = uicheckbox(app.UPDRSTab);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = '';
            app.BASELINEEditField = uieditfield(app.UPDRSTab, 'text');
            app.BASELINEEditField.ValueChangedFcn = createCallbackFcn(app, @BASELINEEditFieldValueChanged, true);
            app.BASELINEEditField.Tooltip = {'Please specify an alphanumeric name WITHOUT any spaces or special characters'; ''};
            app.BASELINEEditField.Value = 'Baseline';
            app.POSTOPEditField = uieditfield(app.UPDRSTab, 'text');
            app.POSTOPEditField.ValueChangedFcn = createCallbackFcn(app, @POSTOPEditFieldValueChanged, true);
            app.POSTOPEditField.Tooltip = {'Please specify an alpha numeric name WITHOUT any spaces or special characters.'; ''};
            app.POSTOPEditField.Value = 'Postop';
            app.CheckBox = uicheckbox(app.UPDRSTab);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.EditField = uieditfield(app.UPDRSTab, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Tooltip = {'Please specify an alpha numeric name WITHOUT any spaces or special characters.'; ''};
            app.EditField.Value = 'Postop2';
            app.EditField_2 = uieditfield(app.UPDRSTab, 'text');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Tooltip = {'Please specify an alpha numeric name WITHOUT any spaces or special characters.'; ''};
            app.EditField_2.Value = 'Postop3';
            app.LateralityButtonGroup = uibuttongroup(app.UPDRSTab);
            app.LateralityButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LateralityButtonGroupSelectionChanged, true);
            app.LateralityButtonGroup.Title = 'Laterality';
            app.BilateralButton = uiradiobutton(app.LateralityButtonGroup);
            app.BilateralButton.Text = 'Bilateral';
            app.BilateralButton.Value = true;
            app.RightButton = uiradiobutton(app.LateralityButtonGroup);
            app.RightButton.Text = 'Right';
            app.LeftButton = uiradiobutton(app.LateralityButtonGroup);
            app.LeftButton.Text = 'Left';
            app.ThresholdTremorScoresCheckBox = uicheckbox(app.UPDRSTab);
            app.ThresholdTremorScoresCheckBox.ValueChangedFcn = createCallbackFcn(app, @ThresholdTremorScoresCheckBoxValueChanged, true);
            app.ThresholdTremorScoresCheckBox.Text = 'Threshold Tremor Scores';
            app.MotorScoreTypeDropDownLabel = uilabel(app.UPDRSTab);
            app.MotorScoreTypeDropDownLabel.Text = 'Motor Score Type';
            app.MotorScoreTypeDropDown = uidropdown(app.UPDRSTab);
            app.MotorScoreTypeDropDown.Items = {'UPDRS', 'MDS', 'Mixed'};
            app.MotorScoreTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @MotorScoreTypeDropDownValueChanged, true);
            app.MotorScoreTypeDropDown.Value = 'Mixed';
            app.BASELINESTARTDropDown_2Label = uilabel(app.UPDRSTab);
            app.BASELINESTARTDropDown_2Label.Text = 'BASELINE START';
            app.BASELINESTARTDropDown_2 = uidropdown(app.UPDRSTab);
            app.BASELINESTARTDropDown_2.DropDownOpeningFcn = createCallbackFcn(app, @BASELINESTARTDropDown_2Opening, true);
            app.BASELINESTOPDropDown_2Label = uilabel(app.UPDRSTab);
            app.BASELINESTOPDropDown_2Label.Text = 'BASELINE STOP';
            app.BASELINESTOPDropDown_2 = uidropdown(app.UPDRSTab);
            app.BASELINESTOPDropDown_2.DropDownOpeningFcn = createCallbackFcn(app, @BASELINESTOPDropDown_2Opening, true);
            app.BASELINESTARTDropDown_2Label_2 = uilabel(app.UPDRSTab);
            app.BASELINESTARTDropDown_2Label_2.Text = 'POSTOP START';
            app.BASELINESTARTDropDown_3 = uidropdown(app.UPDRSTab);
            app.BASELINESTARTDropDown_3.DropDownOpeningFcn = createCallbackFcn(app, @BASELINESTARTDropDown_3Opening, true);
            app.BASELINESTOPDropDown_2Label_2 = uilabel(app.UPDRSTab);
            app.BASELINESTOPDropDown_2Label_2.Text = 'POSTOP STOP';
            app.BASELINESTOPDropDown_3 = uidropdown(app.UPDRSTab);
            app.BASELINESTOPDropDown_3.DropDownOpeningFcn = createCallbackFcn(app, @BASELINESTOPDropDown_3Opening, true);
            app.BASELINESTARTDropDown_2Label_3 = uilabel(app.UPDRSTab);
            app.BASELINESTARTDropDown_2Label_3.Text = 'POSTOP START';
            app.BASELINESTARTDropDown_4 = uidropdown(app.UPDRSTab);
            app.BASELINESTARTDropDown_4.DropDownOpeningFcn = createCallbackFcn(app, @BASELINESTARTDropDown_4Opening, true);
            app.BASELINESTOPDropDown_2Label_3 = uilabel(app.UPDRSTab);
            app.BASELINESTOPDropDown_2Label_3.Text = 'POSTOP STOP';
            app.BASELINESTOPDropDown_4 = uidropdown(app.UPDRSTab);
            app.BASELINESTOPDropDown_4.DropDownOpeningFcn = createCallbackFcn(app, @BASELINESTOPDropDown_4Opening, true);
            app.BASELINESTARTDropDown_2Label_4 = uilabel(app.UPDRSTab);
            app.BASELINESTARTDropDown_2Label_4.Text = 'POSTOP START';
            app.BASELINESTARTDropDown_5 = uidropdown(app.UPDRSTab);
            app.BASELINESTARTDropDown_5.DropDownOpeningFcn = createCallbackFcn(app, @BASELINESTARTDropDown_5Opening, true);
            app.BASELINESTOPDropDown_2Label_4 = uilabel(app.UPDRSTab);
            app.BASELINESTOPDropDown_2Label_4.Text = 'POSTOP STOP';
            app.BASELINESTOPDropDown_5 = uidropdown(app.UPDRSTab);
            app.BASELINESTOPDropDown_5.DropDownOpeningFcn = createCallbackFcn(app, @BASELINESTOPDropDown_5Opening, true);
            app.ImportExcelSheetButton_2 = uibutton(app.UPDRSTab, 'push');
            app.ImportExcelSheetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ImportExcelSheetButton_2Pushed, true);
            app.ImportExcelSheetButton_2.Text = 'Import Excel Sheet..';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ea_score_gen(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end