classdef ea_discfiberexplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ToolsMenu                       matlab.ui.container.Menu
        RefreshInformationfromLeadGroupprojectMenu  matlab.ui.container.Menu
        SetseedforrandomnumbergeneratorMenu  matlab.ui.container.Menu
        AnalysisIDEditFieldLabel        matlab.ui.control.Label
        AnalysisIDEditField             matlab.ui.control.EditField
        LeadGroupPathLabel              matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ConnectomeSelectionPanel        matlab.ui.container.Panel
        CalculateButton                 matlab.ui.control.Button
        ConnectomeDropDownLabel         matlab.ui.control.Label
        ConnectomeDropDown              matlab.ui.control.DropDown
        InteractiveModelSetupPanel      matlab.ui.container.Panel
        MirrorSidesCheckBox             matlab.ui.control.CheckBox
        PatientsListBoxLabel            matlab.ui.control.Label
        PatientsListBox                 matlab.ui.control.ListBox
        VariableofInterestDropDownLabel  matlab.ui.control.Label
        VariableofInterestDropDown      matlab.ui.control.DropDown
        CovariatesListBox               matlab.ui.control.ListBox
        ConnthresholdSlider             matlab.ui.control.Slider
        ConnthresholdLabel              matlab.ui.control.Label
        EfieldthresholdSlider           matlab.ui.control.Slider
        EfieldthresholdLabel            matlab.ui.control.Label
        CovariatesCheckBox              matlab.ui.control.CheckBox
        Efieldmetric                    matlab.ui.control.DropDown
        ModelSetupDropDownLabel         matlab.ui.control.Label
        ModelSetupDropDown              matlab.ui.control.DropDown
        Corrtype                        matlab.ui.control.DropDown
        SubcohortsDropDownLabel         matlab.ui.control.Label
        SubcohortsDropDown              matlab.ui.control.DropDown
        BusyLampLabel                   matlab.ui.control.Label
        BusyLamp                        matlab.ui.control.Lamp
        VisualizationThresholdsPanel    matlab.ui.container.Panel
        ColorButtonPositive             matlab.ui.control.Button
        ColorButtonNegative             matlab.ui.control.Button
        ShowPositiveFibersCheckBox      matlab.ui.control.CheckBox
        ShowNegativeFibersCheckBox      matlab.ui.control.CheckBox
        SplitColorbyGroupVariableCheckBox  matlab.ui.control.CheckBox
        ExportasAtlasButton             matlab.ui.control.Button
        ShowAmountSliderLabel           matlab.ui.control.Label
        ShowAmountSliderPositive        matlab.ui.control.Slider
        LeftSliderLabel                 matlab.ui.control.Label
        LeftSliderPositive              matlab.ui.control.Slider
        RightSliderLabel                matlab.ui.control.Label
        RightSliderPositive             matlab.ui.control.Slider
        LeftSlider_2Label               matlab.ui.control.Label
        LeftSliderNegative              matlab.ui.control.Slider
        ShowAmountSlider_2Label         matlab.ui.control.Label
        ShowAmountSliderNegative        matlab.ui.control.Slider
        RightSlider_2Label              matlab.ui.control.Label
        RightSliderNegative             matlab.ui.control.Slider
        ConsiderSignificantFibersonlyCheckBox  matlab.ui.control.CheckBox
        RefreshViewButton               matlab.ui.control.Button
        AutoRefreshCheckBox             matlab.ui.control.CheckBox
        levelEditFieldLabel             matlab.ui.control.Label
        levelEditField                  matlab.ui.control.NumericEditField
        MultTestStrategy                matlab.ui.control.DropDown
        AbsfiberamtButton               matlab.ui.control.Button
        ColorBar                        matlab.ui.control.UIAxes
        CrossvalidationPredictionPanel  matlab.ui.container.Panel
        TrainOnListBoxLabel             matlab.ui.control.Label
        TrainOnListBox                  matlab.ui.control.ListBox
        PredictListBoxLabel             matlab.ui.control.Label
        PredictListBox                  matlab.ui.control.ListBox
        RunCVButton                     matlab.ui.control.Button
        PosthoccorrectforgroupCheckBox  matlab.ui.control.CheckBox
        StrategyDropDownLabel           matlab.ui.control.Label
        StrategyDropDown                matlab.ui.control.DropDown
        kEditFieldLabel                 matlab.ui.control.Label
        kEditField                      matlab.ui.control.EditField
        LiveVisualizeCheckBox           matlab.ui.control.CheckBox
        BasePredictiononDropDownLabel   matlab.ui.control.Label
        BasePredictiononDropDown        matlab.ui.control.DropDown
        ModelNormalizationDropDownLabel  matlab.ui.control.Label
        ModelNormalizationDropDown      matlab.ui.control.DropDown
        BinsEditFieldLabel              matlab.ui.control.Label
        BinsEditField                   matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        tractset % associated ea_disctract object.
        right_fibs
        left_fibs
    end
    
    
    methods (Access = private)
        
        function results = drawcb(app)
            app.isbusy(1);
            if ~isempty(app.tractset.colorbar)
                if app.tractset.splitbygroup
                    app.ColorBar.Visible = 'off';
                    cla(app.ColorBar);
                else
                    app.ColorBar.Visible = 'on';
                    cb = app.tractset.colorbar;
                    ea_plot_colorbar(cb.cmap{1}, [], 'h', '', cb.tick{1}, cb.ticklabel{1}, app.ColorBar);
                end
            else
                app.ColorBar.Visible = 'off';
                cla(app.ColorBar);
            end
            app.isbusy(0);
        end
        
        function results = enablerules(app)
            app.isbusy(1);
            if ~isfield(app.tractset.results,ea_conn2connid(app.tractset.connectome))
                val='off';
            else
                val=ea_bool2onoff(isfield(app.tractset.results.(ea_conn2connid(app.tractset.connectome)),ea_method2methodid(app.tractset)));
            end
            try
                numsides=numel(app.tractset.results.(ea_conn2connid(app.tractset.connectome)).(ea_method2methodid(app.tractset)).fibsval);
            catch % default
                numsides=2;
            end
            % enable controls:
            app.ModelSetupDropDown.Enable = val;
            app.ModelSetupDropDownLabel.Enable = val;
            
            
            app.MirrorSidesCheckBox.Enable = val;
            app.VariableofInterestDropDown.Enable = val;
            
            groupsassigned=(length(unique(app.tractset.M.patient.group))>1);
            if groupsassigned
                app.SplitColorbyGroupVariableCheckBox.Enable = val;
            else
                app.SplitColorbyGroupVariableCheckBox.Enable = 'off';
                app.SplitColorbyGroupVariableCheckBox.Value = 0;
            end
            
            app.ShowPositiveFibersCheckBox.Enable = val;
            app.ShowNegativeFibersCheckBox.Enable = val;
            app.ShowAmountSliderPositive.Enable = val;
            app.LeftSliderPositive.Enable = val;
            app.RightSliderPositive.Enable = val;
            app.ColorButtonPositive.Enable = val;
            app.ShowNegativeFibersCheckBox.Enable = val;
            
            app.ConnthresholdSlider.Enable = val;
            app.ConnthresholdLabel.Enable = val;
            app.ShowAmountSliderLabel.Enable = val;
            app.LeftSliderLabel.Enable = val;
            app.RightSliderLabel.Enable = val;
            app.VariableofInterestDropDownLabel.Enable = val;
            app.CovariatesCheckBox.Enable = val;
            
            if app.SplitColorbyGroupVariableCheckBox.Value
                app.ColorBar.Visible = 'off';
                cla(app.ColorBar);
                app.ExportasAtlasButton.Enable = 'off';
                cvEnable = 'off';
            else
                app.ColorBar.Visible = val;
                if strcmp(val, 'off')
                    cla(app.ColorBar);
                end
                app.ExportasAtlasButton.Enable = val;
                cvEnable = val;
            end
            
            % CV Panel:
            app.StrategyDropDown.Enable = cvEnable;
            app.StrategyDropDownLabel.Enable = cvEnable;
            app.kEditField.Enable = cvEnable;
            app.kEditFieldLabel.Enable = cvEnable;
            app.RunCVButton.Enable = cvEnable;
            app.PosthoccorrectforgroupCheckBox.Enable = cvEnable;
            app.ModelNormalizationDropDown.Enable = cvEnable;
            app.ModelNormalizationDropDownLabel.Enable = cvEnable;
            switch app.BasePredictiononDropDown.Value
                case {'Histogram','z-scored Histogram'}
                    app.BinsEditField.Enable = cvEnable;
                    app.BinsEditFieldLabel.Enable = cvEnable;
                otherwise
                    app.BinsEditField.Enable = 'off';
                    app.BinsEditFieldLabel.Enable = 'off';
            end
            
            switch app.StrategyDropDown.Value
                case 'k-fold (randomized)'
                    app.kEditField.Visible = 'on';
                    app.kEditFieldLabel.Visible = 'on';
                    app.kEditFieldLabel.Text = 'k:';
                    app.kEditField.Tooltip = '';
                    app.kEditField.Value = num2str(app.tractset.kfold);
                case 'Leave-Nothing-Out (Permutation-Based)'
                    app.kEditField.Visible = 'on';
                    app.kEditFieldLabel.Visible = 'on';
                    app.kEditFieldLabel.Text = '# perms:';
                    app.kEditField.Tooltip = '';
                    app.kEditField.Value = num2str(app.tractset.Nperm);
                case 'Custom (Sets)'
                    app.kEditField.Visible = 'on';
                    app.kEditFieldLabel.Visible = 'on';
                    app.kEditFieldLabel.Text = '# sets:';
                    app.kEditField.Tooltip = 'Randomly split the data into N sets';
                    app.kEditField.Value = num2str(app.tractset.Nsets);
                otherwise
                    app.kEditField.Visible = 'off';
                    app.kEditFieldLabel.Visible = 'off';
                    app.kEditField.Tooltip = '';
            end
            
            switch app.StrategyDropDown.Value
                case 'Custom (Patients)'
                    app.TrainOnListBox.Items=app.tractset.M.patient.list;
                    app.PredictListBox.Items=app.tractset.M.patient.list;
                case 'Custom (Cohorts)'
                    if length(app.tractset.M.groups.group)>1
                        app.TrainOnListBox.Items=sprintfc('%d',(app.tractset.M.groups.group));
                        app.PredictListBox.Items=sprintfc('%d',(app.tractset.M.groups.group));
                    end
                case 'Custom (Sets)'
                    if str2double(app.kEditField.Value)>1
                        app.TrainOnListBox.Items=sprintfc('%d',[1:str2double(app.kEditField.Value)]');
                        app.PredictListBox.Items=sprintfc('%d',[1:str2double(app.kEditField.Value)]');
                    end
                case 'Custom (Subcohorts)'
                    app.TrainOnListBox.Items=app.tractset.setlabels;
                    app.PredictListBox.Items=app.tractset.setlabels;
            end
            
            switch app.StrategyDropDown.Value
                case 'Custom (Patients)'
                    cvlEnable=cvEnable;
                    if isempty(app.TrainOnListBox.Value) || isempty(app.PredictListBox.Value)
                        app.RunCVButton.Enable = 'off';
                    end
                case 'Custom (Cohorts)'
                    if length(app.tractset.M.groups.group)>1
                        cvlEnable=cvEnable;
                        if isempty(app.TrainOnListBox.Value) || isempty(app.PredictListBox.Value)
                            app.RunCVButton.Enable = 'off';
                        end
                    else
                        app.RunCVButton.Enable = 'off';
                        cvlEnable='off';
                    end
                    
                case 'Custom (Sets)'
                    if str2double(app.kEditField.Value)>1
                        if isempty(app.TrainOnListBox.Value) || isempty(app.PredictListBox.Value)
                            app.RunCVButton.Enable = 'off';
                        end
                        cvlEnable=cvEnable;
                    else
                        app.RunCVButton.Enable = 'off';
                        cvlEnable='off';
                    end
                case 'Custom (Subcohorts)'
                    if isempty(app.TrainOnListBox.Value) || isempty(app.PredictListBox.Value)
                        app.RunCVButton.Enable = 'off';
                    end
                    cvlEnable='on';
                otherwise
                    cvlEnable='off';
            end
            
            % CV Listboxes
            app.PredictListBox.Enable = cvlEnable;
            app.PredictListBoxLabel.Enable = cvlEnable;
            app.TrainOnListBox.Enable = cvlEnable;
            app.TrainOnListBoxLabel.Enable = cvlEnable;
            
            app.AutoRefreshCheckBox.Enable = val;
            app.RefreshViewButton.Enable = val;
            app.LiveVisualizeCheckBox.Enable = val;
            
            if ismember(app.Corrtype.Value,{'Skipped Spearman','Skipped Pearson'})
                app.ConsiderSignificantFibersonlyCheckBox.Enable = 'off';
                app.ConsiderSignificantFibersonlyCheckBox.Value = 0;
                app.levelEditField.Enable = 'off';
                app.levelEditFieldLabel.Enable = 'off';
                app.MultTestStrategy.Enable = 'off';
            else
                app.ConsiderSignificantFibersonlyCheckBox.Enable = val;
                app.levelEditField.Enable = val;
                app.levelEditFieldLabel.Enable = val;
                app.MultTestStrategy.Enable = val;
            end
            
            if ismember(app.Corrtype.Value,{'Skipped Spearman','Skipped Pearson','Bend'})
                app.CovariatesCheckBox.Enable = 'off';
                app.CovariatesCheckBox.Value = 0;
            end
            
            app.BasePredictiononDropDown.Enable = val;
            app.BasePredictiononDropDownLabel.Enable = val;
            
            if app.CovariatesCheckBox.Value
                app.CovariatesListBox.Enable = val;
            else
                app.CovariatesListBox.Enable = 'off';
            end
            
            switch app.ModelSetupDropDown.Value
                case 'T-Tests / VTAs (Baldermann 2019)'
                    app.EfieldthresholdSlider.Enable = 'off';
                    app.EfieldthresholdLabel.Enable = 'off';
                    app.Efieldmetric.Enable = 'off';
                    app.Corrtype.Enable = 'off';
                    app.BasePredictiononDropDown.Items={'Mean of Scores','Sum of Scores','Peak of Scores','Peak 5% of Scores','Histogram','z-scored Histogram'};
                    if ~ismember(app.tractset.basepredictionon,lower(app.BasePredictiononDropDown.Items))
                        app.tractset.basepredictionon=lower(app.BasePredictiononDropDown.Value);
                    end
                case 'Correlations / E-fields (Irmen 2020)'
                    app.EfieldthresholdSlider.Enable = val;
                    app.EfieldthresholdLabel.Enable = val;
                    app.Efieldmetric.Enable = val;
                    app.Corrtype.Enable = val;
                    app.BasePredictiononDropDown.Items={'Mean of Scores','Sum of Scores','Peak of Scores','Peak 5% of Scores','Profile of Scores: Spearman','Profile of Scores: Pearson','Profile of Scores: Bend','Histogram','z-scored Histogram'};
                case 'Plain Connections'
                    app.EfieldthresholdSlider.Enable = 'off';
                    app.EfieldthresholdLabel.Enable = 'off';
                    app.Efieldmetric.Enable = 'off';
                    app.CovariatesCheckBox.Value = 0;
                    app.CovariatesCheckBox.Enable = 'off';
                    app.CovariatesListBox.Enable = 'off';
                    app.Corrtype.Enable = 'off';
                    app.ConsiderSignificantFibersonlyCheckBox.Value = 0;
                    app.ConsiderSignificantFibersonlyCheckBox.Enable = 'off';
                    app.levelEditField.Enable = 'off';
                    app.MultTestStrategy.Enable = 'off';
                    app.ShowNegativeFibersCheckBox.Value = 0;
                    app.ShowNegativeFibersCheckBox.Enable = 'off';
                    app.BasePredictiononDropDown.Items={'Sum of Scores','Mean of Scores','Peak of Scores','Peak 5% of Scores'};
                    if ~ismember(app.tractset.basepredictionon,lower(app.BasePredictiononDropDown.Items))
                        app.tractset.basepredictionon=lower(app.BasePredictiononDropDown.Value);
                    end
                    app.PosthoccorrectforgroupCheckBox.Value = 0;
            end
            
            app.PatientsListBox.Enable=val;
            
            app.SubcohortsDropDown.Enable=val;
            app.SubcohortsDropDown.Items=[{'Choose...', 'All Patients', 'Create Subcohort from Selection', 'Create Subcohort from Inverse Selection'},app.tractset.setlabels];
            
            switch app.ShowNegativeFibersCheckBox.Value
                case 0
                    app.ShowAmountSliderNegative.Enable = 'off';
                    app.ColorButtonNegative.Enable = 'off';
                    app.ShowAmountSlider_2Label.Enable = 'off';
                    
                    app.LeftSliderNegative.Enable = 'off';
                    app.RightSliderNegative.Enable = 'off';
                    
                    app.LeftSlider_2Label.Enable = 'off';
                    app.RightSlider_2Label.Enable = 'off';
                    
                case 1
                    app.ShowAmountSliderNegative.Enable = val;
                    app.ColorButtonNegative.Enable = val;
                    app.ShowAmountSlider_2Label.Enable = val;
                    if numsides==2 % usual bilateral case
                        app.LeftSliderNegative.Enable = val;
                        app.RightSliderNegative.Enable = val;
                        
                        app.LeftSlider_2Label.Enable = val;
                        app.RightSlider_2Label.Enable = val;
                    else % scripting case e.g. when using only one side
                        app.LeftSliderNegative.Enable = 'off';
                        app.RightSliderNegative.Enable = 'off';
                        
                        app.LeftSlider_2Label.Enable = 'off';
                        app.RightSlider_2Label.Enable = 'off';
                        
                    end
            end
            
            switch app.ShowPositiveFibersCheckBox.Value
                case 0
                    app.ShowAmountSliderPositive.Enable = 'off';
                    app.ColorButtonPositive.Enable = 'off';
                    app.LeftSliderPositive.Enable = 'off';
                    app.RightSliderPositive.Enable = 'off';
                    
                    app.ShowAmountSliderLabel.Enable = 'off';
                    app.LeftSliderLabel.Enable = 'off';
                    app.RightSliderLabel.Enable = 'off';
                case 1
                    app.ShowAmountSliderPositive.Enable = val;
                    app.ColorButtonPositive.Enable = val;
                    app.ShowAmountSliderLabel.Enable = val;
                    if numsides==2 % usual bilateral case
                        app.LeftSliderPositive.Enable = val;
                        app.RightSliderPositive.Enable = val;
                        
                        app.LeftSliderLabel.Enable = val;
                        app.RightSliderLabel.Enable = val;
                    else % scripting case e.g. when using only one side
                        app.LeftSliderPositive.Enable = 'off';
                        app.RightSliderPositive.Enable = 'off';
                        app.LeftSliderLabel.Enable = 'off';
                        app.RightSliderLabel.Enable = 'off';
                    end
                    
            end
            
            if app.SplitColorbyGroupVariableCheckBox.Value
                app.ColorButtonPositive.Enable = 'off';
                app.ColorButtonNegative.Enable = 'off';
            end
            
            % turn light back to green
            app.isbusy(0);
        end
        
        
        function results = tractset2UI(app)
            app.isbusy(1);
            
            app.ModelSetupDropDown.Enable = 'on';
            app.ModelSetupDropDownLabel.Enable = 'on';
            app.ConnectomeDropDown.Enable = 'on';
            app.ConnectomeDropDownLabel.Enable = 'on';
            app.CalculateButton.Enable = 'on';
            
            % derive everything from tractset obj:
            app.LeadGroupPathLabel.Text=app.tractset.leadgroup;
            app.LeadGroupPathLabel.Tooltip=app.tractset.leadgroup;
            app.AnalysisIDEditField.Value = app.tractset.ID;
            switch app.tractset.statmetric
                case 1
                    app.ModelSetupDropDown.Value = 'T-Tests / VTAs (Baldermann 2019)';
                    if isfield(app.tractset.M,'pseudoM') % custom scripting input
                        app.CovariatesListBox.Items=['Group / Cohort regressor',app.tractset.M.clinical.labels];
                    else % regular case
                        app.CovariatesListBox.Items=['Group / Cohort regressor','Stimulation Amplitude','VTA Volume',app.tractset.M.clinical.labels];
                    end
                    app.ConnthresholdSlider.Limits = [1,49];
                case 2
                    app.ModelSetupDropDown.Value = 'Correlations / E-fields (Irmen 2020)';
                    if isfield(app.tractset.M,'pseudoM') % custom scripting input
                        app.CovariatesListBox.Items=['Group / Cohort regressor','Stimulation Amplitude','E-Field Magnitude Sum',app.tractset.M.clinical.labels];
                    else % regular case
                        app.CovariatesListBox.Items=['Group / Cohort regressor',app.tractset.M.clinical.labels];
                    end
                    app.ConnthresholdSlider.Limits = [0,100];
                case 3
                    app.ModelSetupDropDown.Value = 'Plain Connections';
                    if isfield(app.tractset.M,'pseudoM') % custom scripting input
                        app.CovariatesListBox.Items=['Group / Cohort regressor',app.tractset.M.clinical.labels];
                    else % regular case
                        app.CovariatesListBox.Items=['Group / Cohort regressor','Stimulation Amplitude','VTA Volume',app.tractset.M.clinical.labels];
                    end
                    app.ConnthresholdSlider.Limits = [1,100];
            end
            
            app.Corrtype.Value=app.tractset.corrtype;
            app.ConsiderSignificantFibersonlyCheckBox.Value = app.tractset.showsignificantonly;
            app.levelEditField.Value = (app.tractset.alphalevel);
            app.MultTestStrategy.Value = app.tractset.multcompstrategy;
            try % could be that entry is not in the items list.
                app.BasePredictiononDropDown.Value = app.tractset.basepredictionon;
            end
            if ~isempty(app.tractset.connectome) && ismember(app.tractset.connectome, app.ConnectomeDropDown.Items)
                app.ConnectomeDropDown.Value = app.tractset.connectome;
            end
            
            app.MirrorSidesCheckBox.Value = app.tractset.mirrorsides;
            
            app.PatientsListBox.Items=app.tractset.M.patient.list;
            app.PatientsListBox.Value=app.PatientsListBox.Items(app.tractset.patientselection);
            app.SubcohortsDropDown.Value='Choose...';
            app.SubcohortsDropDown.Items=[{'Choose...'},{'All Patients'},{'Set from Selection'},{'Inverse Set from Selection'},...
                app.tractset.setlabels];
            for set=1:length(app.tractset.setlabels)
                if isequal(app.tractset.patientselection,find(app.tractset.setselections{set}))
                    app.SubcohortsDropDown.Value=app.tractset.setlabels{set};
                end
            end
            if isequal(app.tractset.patientselection,1:length(app.tractset.M.patient.list))
                app.SubcohortsDropDown.Value='All Patients';
            end
            
            app.Efieldmetric.Value=app.tractset.efieldmetric;
            
            if ~isempty(app.tractset.efieldthreshold)
                app.setefieldsliders;
                if strcmp(app.Efieldmetric.Value,'Mean')
                    app.EfieldthresholdSlider.Value = app.tractset.efieldthreshold;
                else
                    app.EfieldthresholdSlider.Value = app.tractset.efieldthreshold/1000;
                end
            end
            
            app.EfieldthresholdLabel.Text={['Tracts connected if ',lower(app.tractset.efieldmetric),' E-Field Magnitude'] ['they traverse is > ',sprintf('%.1f',app.EfieldthresholdSlider.Value),' ',app.getvms]};
            
            app.ConnthresholdSlider.Value = app.tractset.connthreshold;
            
            switch app.tractset.statmetric
                case 1
                    bubbles='VTAs';
                    app.ConnthresholdLabel.Text = {['Tracts connected to > ',sprintf('%.1f',app.ConnthresholdSlider.Value),' %'],['and < ',sprintf('%.1f',100-app.ConnthresholdSlider.Value),' % of ',bubbles]};
                case 2
                    bubbles='E-Fields';
                    app.ConnthresholdLabel.Text = ['Tracts connected to > ',sprintf('%.1f',app.ConnthresholdSlider.Value),' % of ',bubbles];
                case 3
                    bubbles='VTAs';
                    app.ConnthresholdLabel.Text = ['Tracts connected to > ',sprintf('%.1f',app.ConnthresholdSlider.Value),' % of ',bubbles];
            end
            
            app.PosthoccorrectforgroupCheckBox.Value = app.tractset.adjustforgroups;
            
            app.VariableofInterestDropDown.Items=app.tractset.M.clinical.labels;
            app.VariableofInterestDropDown.Value = app.tractset.responsevarlabel;
            
            [~,ix]=ismember(app.VariableofInterestDropDown.Value,app.CovariatesListBox.Items);
            app.CovariatesListBox.Items(ix)=[];
            
            app.CovariatesListBox.Value=app.tractset.covarlabels;
            
            if ~isempty(app.tractset.results)
                app.CovariatesCheckBox.Value=~isempty(app.tractset.covarlabels);
            end
            
            if ~isempty(app.tractset.splitbygroup)
                app.SplitColorbyGroupVariableCheckBox.Value = app.tractset.splitbygroup;
            end
            
            app.ShowPositiveFibersCheckBox.Value = app.tractset.posvisible;
            try
                if app.tractset.statmetric == 3
                    app.ShowPositiveFibersCheckBox.Text = ['Show Connected Fibers (',num2str(sum(app.tractset.stats.pos.available)),')'];
                else
                    app.ShowPositiveFibersCheckBox.Text = ['Show Positive Fibers (',num2str(sum(app.tractset.stats.pos.available)),')'];
                end
            end
            
            app.ShowNegativeFibersCheckBox.Value = app.tractset.negvisible;
            try
                app.ShowNegativeFibersCheckBox.Text = ['Show Negative Fibers (',num2str(sum(app.tractset.stats.neg.available)),')'];
            end
            
            % Amount sliders:
            app.ShowAmountSliderPositive.Value = mean(app.tractset.showposamount);
            app.ShowAmountSliderPositive.Tooltip = sprintf('%d%%', round(app.ShowAmountSliderPositive.Value));
            try
                app.ShowAmountSliderLabel.Text = {'Show Amount', ['(',num2str(sum(app.tractset.stats.pos.shown)),')']};
            end
            
            app.ShowAmountSliderNegative.Value = mean(app.tractset.shownegamount);
            app.ShowAmountSliderNegative.Tooltip = sprintf('%d%%', round(app.ShowAmountSliderNegative.Value));
            try
                app.ShowAmountSlider_2Label.Text = {'Show Amount', ['(',num2str(sum(app.tractset.stats.neg.shown)),')']};
            end
            
            app.LeftSliderPositive.Value = app.tractset.showposamount(2);
            app.LeftSliderPositive.Tooltip = sprintf('%d%%', round(app.LeftSliderPositive.Value));
            try
                app.LeftSliderLabel.Text = {'Left', ['(',num2str(app.tractset.stats.pos.shown(2)),')']};
            end
            
            app.RightSliderPositive.Value = app.tractset.showposamount(1);
            app.RightSliderPositive.Tooltip = sprintf('%d%%', round(app.RightSliderPositive.Value));
            try
                app.RightSliderLabel.Text = {'Right', ['(',num2str(app.tractset.stats.pos.shown(1)),')']};
            end
            
            app.LeftSliderNegative.Value = app.tractset.shownegamount(2);
            app.LeftSliderNegative.Tooltip = sprintf('%d%%', round(app.LeftSliderNegative.Value));
            try
                app.LeftSlider_2Label.Text = {'Left', ['(',num2str(app.tractset.stats.neg.shown(2)),')']};
            end
            
            app.RightSliderNegative.Value = app.tractset.shownegamount(1);
            app.RightSliderNegative.Tooltip = sprintf('%d%%', round(app.RightSliderNegative.Value));
            try
                app.RightSlider_2Label.Text = {'Right', ['(',num2str(app.tractset.stats.neg.shown(1)),')']};
            end
            
            app.LiveVisualizeCheckBox.Value = app.tractset.cvlivevisualize;
            
            app.BinsEditField.Value = app.tractset.numBins;
            app.ModelNormalizationDropDown.Value = app.tractset.modelNormalization;
            
            % Color buttons:
            app.ColorButtonPositive.BackgroundColor = app.tractset.poscolor;
            app.ColorButtonNegative.BackgroundColor = app.tractset.negcolor;
            
            
            % refresh enablerules
            app.enablerules;
            
            app.isbusy(0);
        end
        
        function results = isbusy(app,business,msg)
            if business == 1 % red
                app.BusyLamp.Color = [1.00,0.41,0.16];
                if exist('msg','var')
                    app.BusyLampLabel.Text = msg;
                else
                    app.BusyLampLabel.Text = 'Busy';
                end
            elseif business == 0 % green
                app.BusyLamp.Color = [0.41,1.00,0.16];
                app.BusyLampLabel.Text = '';
            elseif business == 2 % yellow
                app.BusyLamp.Color = [1.00,0.8,0.16];
                if exist('msg','var')
                    app.BusyLampLabel.Text = msg;
                else
                    app.BusyLampLabel.Text = 'Not Refreshed';
                end
            end
        end
        
        function results = draw(app,force)
            if ~exist('force','var')
                force=0;
            end
            app.RefreshViewButton.BackgroundColor = [0.96,0.96,0.96]; % standard grey
            if app.AutoRefreshCheckBox.Value || force
                app.isbusy(1);
                drawnow;
                
                app.tractset.draw;
                app.drawcb;
                app.isbusy(0);
                drawnow;
                app.tractset2UI;
            else
                app.isbusy(2);
                app.RefreshViewButton.BackgroundColor = [1,0.8,0.16]; % yellow
                drawnow;
            end
        end
        
        function Vms = getvms(app)
            switch app.tractset.efieldmetric
                case 'Mean'
                    Vms='V/mm';
                otherwise
                    Vms='V/m';
            end
        end
        
        function ts = setcovars(app) % transfer covariates from app to tractset
            if app.CovariatesCheckBox.Value % include covariates at all
                [is]=ismember(app.tractset.M.clinical.labels,app.CovariatesListBox.Value);
                if any(is)
                    app.tractset.covars = app.tractset.M.clinical.vars(is);
                    app.tractset.covarlabels = app.tractset.M.clinical.labels(is);
                else % none selected
                    app.tractset.covars = {};
                    app.tractset.covarlabels = {};
                end
                % Check whether Amplitude or VTA volumes should be
                % included, as well.
                if ismember('Stimulation Amplitude',app.CovariatesListBox.Value)
                    app.tractset.covars = [{app.tractset.getstimamp},app.tractset.covars];
                    app.tractset.covarlabels = [{'Stimulation Amplitude'},app.tractset.covarlabels];
                end
                
                if ismember('VTA Volume',app.CovariatesListBox.Value)
                    app.tractset.covars = [{app.tractset.getvtavolumes},app.tractset.covars];
                    app.tractset.covarlabels = [{'VTA Volume'},app.tractset.covarlabels];
                end
                
                if ismember('E-Field Magnitude Sum',app.CovariatesListBox.Value)
                    app.tractset.covars = [{app.tractset.getefieldmagnitudes},app.tractset.covars];
                    app.tractset.covarlabels = [{'E-Field Magnitude Sum'},app.tractset.covarlabels];
                end
                if ismember('Group / Cohort regressor',app.CovariatesListBox.Value)
                    app.tractset.covars = [{app.tractset.getcohortregressor},app.tractset.covars];
                    app.tractset.covarlabels = [{'Group / Cohort regressor'},app.tractset.covarlabels];
                end
            else
                app.tractset.covars = {};
                app.tractset.covarlabels = {};
            end
        end
        
        function setefieldsliders(app)
            value = app.tractset.efieldmetric;
            switch lower(value)
                case 'sum'
                    app.EfieldthresholdSlider.Limits=[0.5,5]; % in V/m
                case 'mean'
                    app.EfieldthresholdSlider.Limits=[5,1000]; % in V/mm (!)
                case 'peak'
                    app.EfieldthresholdSlider.Limits=[0.05,2.5]; % in V/m
                case 'peak 5%'
                    app.EfieldthresholdSlider.Limits=[0.05,2]; % in V/m
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, lgpath, resultfig)
            app.isbusy(1,'Loading');
            app.UIFigure.Name='Fiber Filtering Explorer';
            
            % Add modlist (structural connectomes)
            [mdl,sf]=ea_genmodlist;
            app.ConnectomeDropDown.Items=mdl(sf==1);
            
            % Add standard colors for tracts
            app.ColorButtonPositive.BackgroundColor='r';
            app.ColorButtonNegative.BackgroundColor='b';
            
            % Add Value to connthreshsliderlabel
            changingValue=app.EfieldthresholdSlider.Value;
            if ~exist('resultfig','var')
                resultfig = ea_mnifigure;
            end
            
            % use supplied information to setup analysis
            if ~exist('lgpath','var')
                [FileName,PathName]=uigetfile('LEAD_groupanalysis.mat','Please Choose Lead-Group Analysis project...');
                lgpath = fullfile(PathName,FileName);
            end
            
            letsdraw=0; % flag to state whether to draw directly.
            lgpath = GetFullPath(lgpath);
            varinfo=who('-file',lgpath); % check if file is a LEAD_groupanalysis matfile or a stored disctract object with var name 'tractset'
            if ismember('tractset',varinfo)
                D = load(lgpath,'-mat');
                app.tractset = D.tractset; % load stored disctract object
                
                
                % Fix tractset ID in case it has been renamed
                [~, ID] = fileparts(lgpath);
                if ~strcmp(app.tractset.ID, ID)
                    app.tractset.ID = ID;
                end
                
                % Fix group folder in case it has been moved
                tractGroupFolder = strrep(app.tractset.leadgroup,'LEAD_groupanalysis.mat','');
                UIGroupFolder = strrep(lgpath,['fiberfiltering', filesep, app.tractset.ID, '.fibfilt'],'');
                if ~strcmp(tractGroupFolder, UIGroupFolder)
                    app.tractset.analysispath = strrep(app.tractset.analysispath, tractGroupFolder, UIGroupFolder);
                    app.tractset.leadgroup = strrep(app.tractset.leadgroup, tractGroupFolder, UIGroupFolder);
                    app.tractset.M.root = strrep(app.tractset.M.root, tractGroupFolder, UIGroupFolder);
                    if isfield(app.tractset.M,'ui')
                        app.tractset.M.ui.groupdir = strrep(app.tractset.M.ui.groupdir, tractGroupFolder, UIGroupFolder);
                    end
                end
                
                app.tractset.resultfig = resultfig; % attach to figure
                if ~isempty(app.tractset.results)
                    if isfield(app.tractset.results,ea_conn2connid(app.tractset.connectome))
                        letsdraw=1;
                    end
                end
            else % init from M struct of LEAD group file
                app.tractset = ea_disctract;
                app.tractset.initialize(lgpath,resultfig); % initialize which could lead to drawing in case of loaded obj.
            end
            
            if isfield(app.tractset.M,'ui')
                if ismember(app.tractset.M.ui.connectomename, app.ConnectomeDropDown.Items)
                    app.ConnectomeDropDown.Value = app.tractset.M.ui.connectomename;
                end
            end
            app.setefieldsliders;
            
            if letsdraw
                app.draw; % show its results
            else
                tractset2UI(app);
            end
            enablerules(app);
            app.isbusy(0);
            
            hint={'Welcome to the Lead-DBS Fiber Filtering Explorer!'
                'We are excited about your interest in this tool.'
                ''
                'While the explorer can be a powerful tool, the intended use-case is to'
                'create discriminative tract models on *part* (e.g. 70%) of your data only.'
                'You can do so by selecting only a subset of patients in the lower right.'
                'Then, the aim is to cross-validate'
                'results using boot-strapping methods (see lower panel).'
                ''
                'Once you are convinced that the model you created is robust and'
                'yields meaningful predictions within the training & cross-validation set,'
                '(e.g. using leave-one-out or k-fold CV routines in the lower left)'
                'it is intended to finally test the model on the remaining (e.g. 30%)'
                'test set of your data (e.g. using the Custom Strategies in the lower left).'
                ''
                'Please also consider choices of normative connectomes by reading up'
                'the original work and make sure to appropriately cite authors'
                'that acquired or constructed these datasets.'
                ''
                'If unsure, please consult the manual or use our Slack help-channel.'
                'Furthermore, we would like to encourage everyone to explore/adapt'
                'their own code & analyses tailored to the studies specific needs.'
                ''
                'In case of code changes that could be useful for others, as well,'
                'consider contributing them via a pull-request on github.'};
            uigetpref('LeadDBS','FiberFilteringShowUsageHint','Fiber Filtering Explorer',hint,'Makes Sense');
            
        end

        % Value changed function: SplitColorbyGroupVariableCheckBox
        function SplitColorbyGroupVariableCheckBoxValueChanged(app, event)
            value = app.SplitColorbyGroupVariableCheckBox.Value;
            app.tractset.splitbygroup = value;
            enablerules(app);
            app.draw;
            
        end

        % Value changed function: ModelSetupDropDown
        function ModelSetupDropDownValueChanged(app, event)
            switch app.ModelSetupDropDown.Value
                case 'T-Tests / VTAs (Baldermann 2019)'
                    app.tractset.statmetric=1;
                    if app.tractset.connthreshold > 40
                        app.tractset.connthreshold = 20; % reset to a suitable value
                    end
                case 'Correlations / E-fields (Irmen 2020)'
                    app.tractset.statmetric=2;
                    % also make sure efieldmetric sliders are properly set:
                    app.setefieldsliders;
                case 'Plain Connections'
                    app.tractset.statmetric=3;
                    if app.tractset.connthreshold < 1
                        app.tractset.connthreshold = 1; % reset to a suitable value
                    end
            end
            tractset2UI(app);
            enablerules(app);
            if ~isfield(app.tractset.results,ea_conn2connid(app.tractset.connectome))
                val=0;
            else
                val=(isfield(app.tractset.results.(ea_conn2connid(app.tractset.connectome)),ea_method2methodid(app.tractset)));
            end
            if val % already calculated
                app.draw;
            end
        end

        % Value changing function: EfieldthresholdSlider
        function EfieldthresholdSliderValueChanging(app, event)
            changingValue = event.Value;
            app.EfieldthresholdLabel.Text={['Tracts connected if ',lower(app.tractset.efieldmetric),' E-Field Magnitude'] ['they traverse is > ',sprintf('%.2f',changingValue),' ',app.getvms]};
        end

        % Value changed function: VariableofInterestDropDown
        function VariableofInterestDropDownValueChanged(app, event)
            app.CovariatesListBox.Items=['Stimulation Amplitude','VTA Volume',app.tractset.M.clinical.labels];
            [~,ix]=ismember(app.VariableofInterestDropDown.Value,app.CovariatesListBox.Items);
            app.CovariatesListBox.Items(ix)=[];
            
            [~, ix]=ismember(app.VariableofInterestDropDown.Value,app.tractset.M.clinical.labels);
            app.tractset.responsevar = app.tractset.M.clinical.vars{ix};
            app.tractset.responsevarlabel = app.tractset.M.clinical.labels{ix};
            app.draw;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            %app.tractset.app=app; % need to change
            app.tractset.leadgroup=app.LeadGroupPathLabel.Text;
            
            %% set up object properties:
            app.tractset.posvisible = app.ShowPositiveFibersCheckBox.Value;
            app.tractset.negvisible = app.ShowNegativeFibersCheckBox.Value;
            app.tractset.showposamount = [app.RightSliderPositive.Value, app.LeftSliderPositive.Value];
            app.tractset.shownegamount = [app.RightSliderNegative.Value, app.LeftSliderNegative.Value];
            app.tractset.poscolor = app.ColorButtonPositive.BackgroundColor;
            app.tractset.negcolor = app.ColorButtonNegative.BackgroundColor;
            [~, ix]=find(ismember(app.PatientsListBox.Items,app.PatientsListBox.Value));
            app.tractset.patientselection = ix;
            
            [~, ix]=ismember(app.VariableofInterestDropDown.Value,app.tractset.M.clinical.labels);
            app.tractset.responsevar = app.tractset.M.clinical.vars{ix};
            app.tractset.responsevarlabel = app.tractset.M.clinical.labels{ix};
            
            setcovars(app);
            
            switch app.ModelSetupDropDown.Value
                case 'T-Tests / VTAs (Baldermann 2019)'
                    app.tractset.statmetric=1;
                case 'Correlations / E-fields (Irmen 2020)'
                    app.tractset.statmetric=2;
                case 'Plain Connections'
                    app.tractset.statmetric=3;
            end
            
            app.tractset.connectome = app.ConnectomeDropDown.Value;
            app.setefieldsliders;

            if strcmp(app.Efieldmetric.Value,'Mean')
                app.tractset.efieldthreshold = app.EfieldthresholdSlider.Value;
            else
                app.tractset.efieldthreshold = app.EfieldthresholdSlider.Value*1000;
            end
            app.tractset.connthreshold = app.ConnthresholdSlider.Value;
            app.tractset.mirrorsides = app.MirrorSidesCheckBox.Value;
            
            % calculate general setup
            app.tractset.calculate;
            
            % save dataset:
            app.tractset.save;
            
            enablerules(app);
            
            % draw results
            app.draw;
        end

        % Callback function
        function IncludeallpatientsCheckBoxValueChanged(app, event)
            value = app.IncludeallpatientsCheckBox.Value;
            switch value
                case 1
                    app.PatientsListBox.Value=app.PatientsListBox.Items;
                    app.tractset.patientselection = 1:length(app.PatientsListBox.Items);
                case 0
            end
            enablerules(app);
        end

        % Value changed function: ShowPositiveFibersCheckBox
        function ShowPositiveFibersCheckBoxValueChanged(app, event)
            value = app.ShowPositiveFibersCheckBox.Value;
            app.tractset.posvisible = value;
            enablerules(app);
            app.draw;
            
        end

        % Value changed function: ShowNegativeFibersCheckBox
        function ShowNegativeFibersCheckBoxValueChanged(app, event)
            value = app.ShowNegativeFibersCheckBox.Value;
            app.tractset.negvisible = value;
            enablerules(app);
            app.draw;
            
        end

        % Value changing function: ShowAmountSliderPositive
        function ShowAmountSliderPositiveValueChanging(app, event)
            changingValue = event.Value;
            app.LeftSliderPositive.Value = changingValue;
            app.RightSliderPositive.Value = changingValue;
            app.tractset.showposamount = [app.RightSliderPositive.Value, app.LeftSliderPositive.Value];
        end

        % Value changing function: ShowAmountSliderNegative
        function ShowAmountSliderNegativeValueChanging(app, event)
            changingValue = event.Value;
            app.LeftSliderNegative.Value = changingValue;
            app.RightSliderNegative.Value = changingValue;
            app.tractset.shownegamount = [app.RightSliderNegative.Value, app.LeftSliderNegative.Value];
            
        end

        % Value changing function: AnalysisIDEditField
        function AnalysisIDEditFieldValueChanging(app, event)
            changingValue = event.Value;
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,' ','_');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'(','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,')','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'-','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'.','');
            %
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'"','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'~','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'#','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'^','');
            %
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'|','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'$','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'%','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'&','');
            %
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'*','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'!','');
        end

        % Value changed function: AnalysisIDEditField
        function AnalysisIDEditFieldValueChanged(app, event)
            value = app.AnalysisIDEditField.Value;
            if exist([fileparts(app.tractset.leadgroup),filesep,'fiberfiltering',filesep,value,'.fibfilt'],'file')
                app.AnalysisIDEditField.Value = app.tractset.ID;
                msgbox('A tract with this name already exists. Please choose a different name.')
                return
            end
            
            if exist([fileparts(app.tractset.leadgroup),filesep,'fiberfiltering',filesep,app.tractset.ID,'.fibfilt'],'file')
                movefile([fileparts(app.tractset.leadgroup),filesep,'fiberfiltering',filesep,app.tractset.ID,'.fibfilt'],...
                    [fileparts(app.tractset.leadgroup),filesep,'fiberfiltering',filesep,value,'.fibfilt']);
            end
            
            ht = getappdata(app.tractset.resultfig, 'ht');
            if ~isempty(ht)
                Tags = arrayfun(@(tool) tool.Tag, ht.Children, 'Uni', 0);
                pushToolInd = find(contains(Tags,['Explore discriminative fibertract ', app.tractset.ID]),1);
                set(ht.Children(pushToolInd), 'Tag', ['Explore discriminative fibertract ', value],...
                    'TooltipString', ['Explore discriminative fibertract ', value], ...
                    'ClickedCallback', {@ea_add_discfiber, [fileparts(app.tractset.leadgroup),filesep,'fiberfiltering',filesep,value,'.fibfilt'], app.tractset.resultfig});
            end
            
            app.tractset.ID=value;
            app.tractset.save;
            
        end

        % Value changed function: PatientsListBox
        function PatientsListBoxValueChanged(app, event)
            [~, ix]=find(ismember(app.PatientsListBox.Items,app.PatientsListBox.Value));
            app.tractset.patientselection = ix;
            app.draw;
            app.tractset2UI;
        end

        % Value changed function: CovariatesListBox
        function CovariatesListBoxValueChanged(app, event)
            setcovars(app);
            app.draw;
        end

        % Value changed function: LeftSliderPositive
        function LeftSliderPositiveValueChanged(app, event)
            app.tractset.showposamount = [app.RightSliderPositive.Value, app.LeftSliderPositive.Value];
            app.LeftSliderPositive.Tooltip = sprintf('%d%%', round(app.LeftSliderPositive.Value));
            app.draw;
        end

        % Value changed function: RightSliderPositive
        function RightSliderPositiveValueChanged(app, event)
            app.tractset.showposamount = [app.RightSliderPositive.Value, app.LeftSliderPositive.Value];
            app.RightSliderPositive.Tooltip = sprintf('%d%%', round(app.RightSliderPositive.Value));
            app.draw;
        end

        % Value changed function: LeftSliderNegative
        function LeftSliderNegativeValueChanged(app, event)
            app.tractset.shownegamount = [app.RightSliderNegative.Value, app.LeftSliderNegative.Value];
            app.LeftSliderNegative.Tooltip = sprintf('%d%%', round(app.LeftSliderNegative.Value));
            app.draw;
        end

        % Value changed function: RightSliderNegative
        function RightSliderNegativeValueChanged(app, event)
            app.tractset.shownegamount = [app.RightSliderNegative.Value, app.LeftSliderNegative.Value];
            app.RightSliderNegative.Tooltip = sprintf('%d%%', round(app.RightSliderNegative.Value));
            app.draw;
        end

        % Button pushed function: ColorButtonPositive
        function ColorButtonPositivePushed(app, event)
            c=uisetcolor;
            app.ColorButtonPositive.BackgroundColor=c;
            app.tractset.poscolor = app.ColorButtonPositive.BackgroundColor;
            app.draw;
        end

        % Button pushed function: ColorButtonNegative
        function ColorButtonNegativePushed(app, event)
            c=uisetcolor;
            app.ColorButtonNegative.BackgroundColor=c;
            app.tractset.negcolor = app.ColorButtonNegative.BackgroundColor;
            app.draw;
        end

        % Value changed function: EfieldthresholdSlider
        function EfieldthresholdSliderValueChanged(app, event)
            if strcmp(app.Efieldmetric.Value,'Mean')
                app.tractset.efieldthreshold = app.EfieldthresholdSlider.Value;
            else
                app.tractset.efieldthreshold = app.EfieldthresholdSlider.Value*1000;
            end
            app.draw;
        end

        % Value changed function: ConnectomeDropDown
        function ConnectomeDropDownValueChanged(app, event)
            value = app.ConnectomeDropDown.Value;
            app.tractset.connectome = value;
            enablerules(app);
            if ~isfield(app.tractset.results,ea_conn2connid(app.tractset.connectome))
                val=0;
            else
                val=(isfield(app.tractset.results.(ea_conn2connid(app.tractset.connectome)),ea_method2methodid(app.tractset)));
            end
            if val % already calculated
                app.draw;
            end
        end

        % Value changed function: MirrorSidesCheckBox
        function MirrorSidesCheckBoxValueChanged(app, event)
            value = app.MirrorSidesCheckBox.Value;
            app.tractset.mirrorsides = app.MirrorSidesCheckBox.Value;
            app.draw;
        end

        % Value changed function: ShowAmountSliderPositive
        function ShowAmountSliderPositiveValueChanged(app, event)
            value = app.ShowAmountSliderPositive.Value;
            app.LeftSliderPositive.Value = value;
            app.RightSliderPositive.Value = value;
            app.ShowAmountSliderPositive.Tooltip = sprintf('%d%%', round(value));
            app.LeftSliderPositive.Tooltip = sprintf('%d%%', round(value));
            app.RightSliderPositive.Tooltip = sprintf('%d%%', round(value));
            app.tractset.showposamount = [app.RightSliderPositive.Value, app.LeftSliderPositive.Value];
            app.draw;
        end

        % Value changed function: ShowAmountSliderNegative
        function ShowAmountSliderNegativeValueChanged(app, event)
            value = app.ShowAmountSliderNegative.Value;
            app.LeftSliderNegative.Value = value;
            app.RightSliderNegative.Value = value;
            app.ShowAmountSliderNegative.Tooltip = sprintf('%d%%', round(value));
            app.LeftSliderNegative.Tooltip = sprintf('%d%%', round(value));
            app.RightSliderNegative.Tooltip = sprintf('%d%%', round(value));
            app.tractset.shownegamount = [app.RightSliderNegative.Value, app.LeftSliderNegative.Value];
            app.draw;
        end

        % Value changing function: ConnthresholdSlider
        function ConnthresholdSliderValueChanging(app, event)
            changingValue = event.Value;
            switch app.tractset.statmetric
                case 1
                    bubbles='VTAs';
                    app.ConnthresholdLabel.Text = {['Tracts connected to > ',sprintf('%.1f',changingValue),' %'],['and < ',sprintf('%.1f',100-changingValue),' % of ',bubbles]};
                case 2
                    bubbles='E-Fields';
                    app.ConnthresholdLabel.Text = ['Tracts connected to > ',sprintf('%.1f',changingValue),' % of ',bubbles];
                case 3
                    bubbles='VTAs';
                    app.ConnthresholdLabel.Text = ['Tracts connected to > ',sprintf('%.1f',changingValue),' % of ',bubbles];
            end
        end

        % Value changed function: ConnthresholdSlider
        function ConnthresholdSliderValueChanged(app, event)
            value = app.ConnthresholdSlider.Value;
            app.tractset.connthreshold = value;
            app.draw;
        end

        % Button pushed function: RunCVButton
        function RunCVButtonPushed(app, event)
            switch app.StrategyDropDown.Value
                case 'Leave-Nothing-Out (Permutation-Based)'
                    app.tractset.customselection = [];
                    [I,Ihat,R0,R1,pperm]=app.tractset.lnopb;
                    I = I(:,1);
                    Ihat = Ihat{1};
                    cvs='lnopb';
                    sel=app.tractset.patientselection;
                    h1=ea_plothistperm([app.tractset.responsevarlabel,' [Permutation-Based Test]'],[R1;R0],{'Unpermuted prediction'},{1},1);
                    saveas(h1,[fileparts(app.tractset.leadgroup),filesep,'fiberfiltering',filesep,app.tractset.ID,'_',app.tractset.responsevarlabel,'_',cvs,'_permtest.png']);
                case 'Leave-One-Patient-Out'
                    app.tractset.customselection = [];
                    [I,Ihat]=app.tractset.loocv;
                    cvs='loocv';
                    sel=app.tractset.patientselection;
                case 'Leave-One-Cohort-Out'
                    app.tractset.customselection = [];
                    [I,Ihat]=app.tractset.lococv;
                    cvs='lococv';
                    sel=app.tractset.patientselection;
                case 'k-fold (randomized)'
                    app.tractset.customselection = [];
                    [I,Ihat]=app.tractset.kfoldcv;
                    cvs='kfoldcv';
                    sel=app.tractset.patientselection;
                case 'Custom (Patients)'
                    cvp.NumTestSets = 1;
                    % training and test indices from the items list
                    training = find(ismember(app.TrainOnListBox.Items,app.TrainOnListBox.Value));
                    test = find(ismember(app.PredictListBox.Items,app.PredictListBox.Value));
                    % Patient selected based on the training and test indices
                    app.tractset.customselection = unique([training, test]);
                    % Construct cvp struct
                    cvp.training{1} = ismember(app.tractset.customselection, training);
                    cvp.test{1} = ismember(app.tractset.customselection, test);
                    [I, Ihat]=app.tractset.crossval(cvp);
                    cvs = 'custom_pts';
                    sel = test;
                case 'Custom (Cohorts)'
                    cvp.NumTestSets = 1;
                    % training and test indices from the items list
                    training = find(ismember(app.tractset.M.patient.group, str2double(app.TrainOnListBox.Value)))';
                    test = find(ismember(app.tractset.M.patient.group,str2double(app.PredictListBox.Value)))';
                    % Patient selected based on the training and test indices
                    app.tractset.customselection = unique([training, test]);
                    % Construct cvp struct
                    cvp.training{1} = ismember(app.tractset.customselection, training);
                    cvp.test{1} = ismember(app.tractset.customselection, test);
                    [I, Ihat]=app.tractset.crossval(cvp);
                    cvs = 'custom_cohs';
                    sel = test;
                case 'Custom (Subcohorts)'
                    cvp.NumTestSets = 1;
                    % training and test indices from the items list
                    tsets = find(ismember(app.tractset.setlabels, app.TrainOnListBox.Value));
                    training=[];
                    for ts=1:length(tsets)
                        training = [training,find(app.tractset.setselections{tsets(ts)})];
                    end
                    training=unique(training);
                    tsets = find(ismember(app.tractset.setlabels, app.PredictListBox.Value));
                    test=[];
                    for ts=1:length(tsets)
                        test = [test,find(app.tractset.setselections{tsets(ts)})];
                    end
                    test=unique(test);
                    % Patient selected based on the training and test indices
                    app.tractset.customselection = unique([training, test]);
                    % Construct cvp struct
                    cvp.training{1} = ismember(app.tractset.customselection, training);
                    cvp.test{1} = ismember(app.tractset.customselection, test);
                    [I, Ihat]=app.tractset.crossval(cvp);
                    cvs = 'custom_subcohs';
                    sel = test;
                case 'Custom (Sets)'
                    cvp.NumTestSets = 1;
                    % Generate cvpartition
                    rng(app.tractset.rngseed);
                    cvpKfold = cvpartition(length(app.tractset.M.patient.list), 'KFold', str2double(app.kEditField.Value));
                    % Calculate training and test indices based on the sets selected and the cvpartitions generated
                    trainSetInd = str2double(app.TrainOnListBox.Value');
                    testSetInd = str2double(app.PredictListBox.Value');
                    training = zeros(size(app.tractset.M.patient.list));
                    test = zeros(size(app.tractset.M.patient.list));
                    for i=1:length(trainSetInd)
                        training = training | cvpKfold.test(trainSetInd(i));
                    end
                    for i=1:length(testSetInd)
                        test = test | cvpKfold.test(testSetInd(i));
                    end
                    training = find(training);
                    test = find(test);
                    % Patient selected based on the training and test indices
                    app.tractset.customselection = unique([training, test]);
                    % Construct cvp struct
                    cvp.training{1} = ismember(app.tractset.customselection, training);
                    cvp.test{1} = ismember(app.tractset.customselection, test);
                    [I, Ihat]=app.tractset.crossval(cvp);
                    
                    cvs = 'custom_sets';
                    sel = test;
            end
            % check if groups exist:
            if length(unique(app.tractset.M.patient.group))>1 && app.PosthoccorrectforgroupCheckBox.Value
                Ihat=ea_resid(ea_cohortregressor(app.tractset.M.patient.group(sel)),Ihat);
                suffix=' [Corrected for Cohort]';
            else
                suffix='';
            end
            
            groupID = app.tractset.M.patient.group(sel);
            groupColors = app.tractset.M.groups.color(unique(groupID),:);
            groupColors = rgb2hsv(groupColors);
            groupColors(:,2) = groupColors(:,2)+((1-groupColors(:,2))/2);
            groupColors=hsv2rgb(groupColors);
            
            if exist('pperm', 'var')
                h=ea_corrplot(I,(Ihat),{['Disc. Fiber prediction ',upper(cvs)],'Empirical',['Fiber-Score',suffix]},'permutation_spearman',groupID,[],pperm,groupColors);
            else
                h=ea_corrplot(I,(Ihat),{['Disc. Fiber prediction ',upper(cvs)],'Empirical',['Fiber-Score',suffix]},'permutation_spearman',groupID,[],[],groupColors);
            end
            saveas(h,[fileparts(app.tractset.leadgroup),filesep,'fiberfiltering',filesep,app.tractset.ID,'_',app.tractset.responsevarlabel,'_',cvs,'.png']);
        end

        % Value changed function: StrategyDropDown
        function StrategyDropDownValueChanged(app, event)
            app.enablerules;
            app.tractset2UI;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.isbusy(1,'saving');
            app.tractset.save;
            for tract=1:numel(app.tractset.drawobject)
                delete(app.tractset.drawobject{tract});
            end
            app.isbusy(0);
            delete(app)
        end

        % Value changed function: Efieldmetric
        function EfieldmetricValueChanged(app, event)
            value = app.Efieldmetric.Value;
            app.setefieldsliders;

            
            app.enablerules;
            app.tractset2UI;
            
            if ~isfield(app.tractset.results,ea_conn2connid(app.tractset.connectome))
                val=0;
            else
                val=(isfield(app.tractset.results.(ea_conn2connid(app.tractset.connectome)),ea_method2methodid(app.tractset)));
            end
            
            if val % already calculated
                app.draw;
            end
        end

        % Value changed function: kEditField
        function kEditFieldValueChanged(app, event)
            switch app.StrategyDropDown.Value
                case 'Leave-Nothing-Out (Permutation-Based)'
                    app.tractset.Nperm = str2double(app.kEditField.Value);
                case 'k-fold (randomized)'
                    app.tractset.kfold = str2double(app.kEditField.Value);
                case 'Custom (Sets)'
                    app.tractset.Nsets = str2double(app.kEditField.Value);
                    if str2double(app.kEditField.Value)>1
                        app.TrainOnListBox.Items=sprintfc('%d',[1:app.tractset.Nsets]');
                        app.PredictListBox.Items=sprintfc('%d',[1:app.tractset.Nsets]');
                    end
            end
        end

        % Value changed function: CovariatesCheckBox
        function CovariatesCheckBoxValueChanged(app, event)
            value = app.CovariatesCheckBox.Value;
            app.enablerules;
            if ~value
                app.tractset.covars = {};
                app.tractset.covarlabels = {};
                app.draw;
            else
                setcovars(app);
                app.draw;
            end
            
        end

        % Value changed function: PosthoccorrectforgroupCheckBox
        function PosthoccorrectforgroupCheckBoxValueChanged(app, event)
            value = app.PosthoccorrectforgroupCheckBox.Value;
            app.tractset.adjustforgroups=value;
        end

        % Menu selected function: 
        % RefreshInformationfromLeadGroupprojectMenu
        function RefreshInformationfromLeadGroupprojectMenuSelected(app, event)
            app.tractset.refreshlg;
            app.tractset2UI;
        end

        % Button pushed function: RefreshViewButton
        function RefreshViewButtonPushed(app, event)
            app.draw(1); % draw with force = 1.
        end

        % Value changed function: 
        % ConsiderSignificantFibersonlyCheckBox
        function ConsiderSignificantFibersonlyCheckBoxValueChanged(app, event)
            value = app.ConsiderSignificantFibersonlyCheckBox.Value;
            app.tractset.showsignificantonly=value;
            app.draw;
        end

        % Button pushed function: ExportasAtlasButton
        function ExportasAtlasButtonPushed(app, event)
            
            disctract = app.tractset.fiberdrawn;
            disctract.info.Connectome = app.tractset.connectome;
            if app.ShowPositiveFibersCheckBox.Value
                disctract.info.PosAmount = sum(app.tractset.stats.pos.shown);
                if app.right_fibs && app.left_fibs
                    disctract.info.PosAmountRight = app.right_fibs;
                    disctract.info.PosAmountLeft = app.left_fibs;
                    disctract.info.PosPercentRight = (disctract.info.PosAmount./app.right_fibs)*100;
                    disctract.info.PosPercentLeft = (disctract.info.PosAmount./app.left_fibs)*100;
                
                else
                    if numel(app.tractset.stats.pos.shown)==2
                        disctract.info.PosAmountRight = sum(app.tractset.stats.pos.shown(1));
                        disctract.info.PosAmountLeft = sum(app.tractset.stats.pos.shown(2));
                        disctract.info.PosPercentRight = app.RightSliderPositive.Value;
                        disctract.info.PosPercentLeft = app.LeftSliderPositive.Value;
                    end
                    disctract.info.PosPercent = app.ShowAmountSliderPositive.Value;
                end
                
            end
            if app.ShowNegativeFibersCheckBox.Value
                disctract.info.NegAmount = sum(app.tractset.stats.neg.shown);
                if numel(app.tractset.stats.neg.shown)==2
                    disctract.info.NegAmountRight = sum(app.tractset.stats.neg.shown(1));
                    disctract.info.NegAmountLeft = sum(app.tractset.stats.neg.shown(2));
                    disctract.info.NegPercentRight = app.RightSliderNegative.Value;
                    disctract.info.NegPercentLeft = app.LeftSliderNegative.Value;
                end
                disctract.info.NegPercent = app.ShowAmountSliderNegative.Value;
            end
            if app.left_fibs < length(disctract.fibcell{1,1})
                disctract.fibcell{1,1}(app.left_fibs+1:end) = [];
                disctract.vals{1,1}(app.left_fibs+1:end) = [];
            else
                disp("Specify a value less than the total number of fibers avaialble for export!");
            end
            if app.right_fibs < length(disctract.fibcell{1,2})
                disctract.fibcell{1,2}(app.right_fibs+1:end) = [];
                disctract.vals{1,2}(app.right_fibs+1:end) = [];
            else
                disp("Specify a value less than the total number of fibers avaialble for export!");
            end
            
            atlasname = app.tractset.ID;
            tractname = 'Tract';
            negativecolor = app.tractset.negcolor;
            positivecolor = app.tractset.poscolor;
            ea_discfibers_exportatlas(disctract, atlasname, tractname, negativecolor, positivecolor);
        end

        % Callback function
        function CPSetsSetsEditFieldValueChanged(app, event)
            value = app.CPSetsSetsEditField.Value;
            app.tractset2UI;
        end

        % Value changed function: TrainOnListBox
        function TrainOnListBoxValueChanged(app, event)
            value = app.TrainOnListBox.Value;
            inverse = app.PredictListBox.Items;
            [~, ix] = ismember(value,inverse);
            inverse(ix)=[];
            app.PredictListBox.Value=inverse;
            app.enablerules;
        end

        % Callback function
        function CPCohsTrainOnListBoxValueChanged(app, event)
            value = app.CPCohsTrainOnListBox.Value;
            inverse = app.CPCohsPredictListBox.Items;
            [~, ix] = ismember(value,inverse);
            inverse(ix)=[];
            app.CPCohsPredictListBox.Value=inverse;
        end

        % Callback function
        function CPSetsTrainOnListBoxValueChanged(app, event)
            value = app.CPSetsTrainOnListBox.Value;
            inverse = app.CPSetsPredictListBox.Items;
            [~, ix] = ismember(value,inverse);
            inverse(ix)=[];
            app.CPSetsPredictListBox.Value=inverse;
        end

        % Value changed function: PredictListBox
        function PredictListBoxValueChanged(app, event)
            value = app.PredictListBox.Value;
            app.enablerules;
        end

        % Value changed function: levelEditField
        function levelEditFieldValueChanged(app, event)
            value = app.levelEditField.Value;
            app.tractset.alphalevel=(value);
            app.draw;
        end

        % Value changed function: MultTestStrategy
        function MultTestStrategyValueChanged(app, event)
            value = app.MultTestStrategy.Value;
            app.tractset.multcompstrategy=value;
            app.draw;
        end

        % Value changed function: Corrtype
        function CorrtypeValueChanged(app, event)
            value = app.Corrtype.Value;
            app.tractset.corrtype=value;
            switch value
                case {'Skipped Pearson','Skipped Spearman'}
                    app.tractset.showsignificantonly=0;
            end
            app.enablerules;
            app.draw;
        end

        % Value changed function: LiveVisualizeCheckBox
        function LiveVisualizeCheckBoxValueChanged(app, event)
            value = app.LiveVisualizeCheckBox.Value;
            app.tractset.cvlivevisualize=value;
        end

        % Value changed function: BasePredictiononDropDown
        function BasePredictiononDropDownValueChanged(app, event)
            value = app.BasePredictiononDropDown.Value;
            app.tractset.basepredictionon=lower(value);
            app.enablerules;
        end

        % Value changed function: SubcohortsDropDown
        function SubcohortsDropDownValueChanged(app, event)
            value = app.SubcohortsDropDown.Value;
            switch value
                case 'All Patients'
                    app.PatientsListBox.Value=app.PatientsListBox.Items;
                    app.tractset.patientselection = 1:length(app.PatientsListBox.Items);
                case 'Create Subcohort from Selection'
                    setname=inputdlg('Specify Set Name','Create New Set');
                    setname=setname{1};
                    setidx=ismember(app.PatientsListBox.Items,app.PatientsListBox.Value);
                    app.tractset.setlabels{end+1}=setname;
                    app.tractset.setselections{end+1}=setidx;
                case 'Create Subcohort from Inverse Selection'
                    setname=inputdlg('Specify Set Name','Create New Set');
                    setname=setname{1};
                    setidx=~ismember(app.PatientsListBox.Items,app.PatientsListBox.Value);
                    app.tractset.setlabels{end+1}=setname;
                    app.tractset.setselections{end+1}=setidx;
                case 'Choose...' % do nothing
                otherwise
                    [~,whichset]=ismember(value,app.tractset.setlabels);
                    app.PatientsListBox.Value=app.PatientsListBox.Items(app.tractset.setselections{whichset});
            end
            % draw:
            [~, ix]=find(ismember(app.PatientsListBox.Items,app.PatientsListBox.Value));
            app.tractset.patientselection = ix;
            app.draw;
            % always reset to "Choose..."
            app.enablerules;
        end

        % Menu selected function: 
        % SetseedforrandomnumbergeneratorMenu
        function SetseedforrandomnumbergeneratorMenuSelected(app, event)
            rngseed = app.tractset.rngseed;
            if ischar(rngseed) && strcmp(rngseed, 'default')
                rngseed = 0;
            end
            rngseed = inputdlg('Input seed for random number generator:', 'Random Seed', [1 40], {num2str(rngseed)});
            if ~isempty(rngseed)
                app.tractset.rngseed = str2double(rngseed{1});
            end
        end

        % Value changing function: RightSliderNegative
        function RightSliderNegativeValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changed function: ModelNormalizationDropDown
        function ModelNormalizationDropDownValueChanged(app, event)
            value = app.ModelNormalizationDropDown.Value;
            app.tractset.modelNormalization=value;
        end

        % Value changed function: BinsEditField
        function BinsEditFieldValueChanged(app, event)
            value = app.BinsEditField.Value;
            app.numBins=value;
        end

        % Button pushed function: AbsfiberamtButton
        function AbsfiberamtButtonPushed(app, event)
            f = uifigure('Position',[100 100 366 200]);
            num_r = uieditfield(f,'numeric','Position',[150 85 50 20]);
            lbl_r = uilabel(f,'Text','Right Fibers','Position',[80 85 70 20]);
            num_l = uieditfield(f,'numeric','Position',[150 60 50 20]);
            lbl_l = uilabel(f,'Text','Left Fibers','Position',[80 65 70 20]);
            btn = uibutton(f,'Text','OK','Position',[300 25 50 20],'ButtonPushedFcn', @(btn,event) getFibVals(btn));
            function getFibVals(btn)
                app.right_fibs = num_r.Value;
                app.left_fibs = num_l.Value;
                close(f);
            end 
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.RefreshInformationfromLeadGroupprojectMenu = uimenu(app.ToolsMenu);
            app.RefreshInformationfromLeadGroupprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @RefreshInformationfromLeadGroupprojectMenuSelected, true);
            app.RefreshInformationfromLeadGroupprojectMenu.Text = 'Refresh Information from Lead-Group project';
            app.SetseedforrandomnumbergeneratorMenu = uimenu(app.ToolsMenu);
            app.SetseedforrandomnumbergeneratorMenu.MenuSelectedFcn = createCallbackFcn(app, @SetseedforrandomnumbergeneratorMenuSelected, true);
            app.SetseedforrandomnumbergeneratorMenu.Text = 'Set seed for random number generator';
            app.AnalysisIDEditFieldLabel = uilabel(app.UIFigure);
            app.AnalysisIDEditFieldLabel.Text = 'Analysis ID';
            app.AnalysisIDEditField = uieditfield(app.UIFigure, 'text');
            app.AnalysisIDEditField.ValueChangedFcn = createCallbackFcn(app, @AnalysisIDEditFieldValueChanged, true);
            app.AnalysisIDEditField.ValueChangingFcn = createCallbackFcn(app, @AnalysisIDEditFieldValueChanging, true);
            app.AnalysisIDEditField.Value = 'new_tract_analysis';
            app.LeadGroupPathLabel = uilabel(app.UIFigure);
            app.LeadGroupPathLabel.Text = 'Lead-Group Path';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '<';
            app.ConnectomeSelectionPanel = uipanel(app.UIFigure);
            app.ConnectomeSelectionPanel.Title = 'Connectome Selection';
            app.CalculateButton = uibutton(app.ConnectomeSelectionPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ConnectomeDropDownLabel = uilabel(app.ConnectomeSelectionPanel);
            app.ConnectomeDropDownLabel.Text = 'Connectome';
            app.ConnectomeDropDown = uidropdown(app.ConnectomeSelectionPanel);
            app.ConnectomeDropDown.Items = {'', ''};
            app.ConnectomeDropDown.ValueChangedFcn = createCallbackFcn(app, @ConnectomeDropDownValueChanged, true);
            app.ConnectomeDropDown.Value = '';
            app.InteractiveModelSetupPanel = uipanel(app.UIFigure);
            app.InteractiveModelSetupPanel.Title = 'Interactive Model Setup';
            app.MirrorSidesCheckBox = uicheckbox(app.InteractiveModelSetupPanel);
            app.MirrorSidesCheckBox.ValueChangedFcn = createCallbackFcn(app, @MirrorSidesCheckBoxValueChanged, true);
            app.MirrorSidesCheckBox.Enable = 'off';
            app.MirrorSidesCheckBox.Text = 'Mirror VTAs / Efields';
            app.PatientsListBoxLabel = uilabel(app.InteractiveModelSetupPanel);
            app.PatientsListBoxLabel.Enable = 'off';
            app.PatientsListBoxLabel.Text = 'Patients';
            app.PatientsListBox = uilistbox(app.InteractiveModelSetupPanel);
            app.PatientsListBox.Multiselect = 'on';
            app.PatientsListBox.ValueChangedFcn = createCallbackFcn(app, @PatientsListBoxValueChanged, true);
            app.PatientsListBox.Enable = 'off';
            app.PatientsListBox.Value = {'Item 1'};
            app.VariableofInterestDropDownLabel = uilabel(app.InteractiveModelSetupPanel);
            app.VariableofInterestDropDownLabel.Enable = 'off';
            app.VariableofInterestDropDownLabel.Text = 'Variable of Interest';
            app.VariableofInterestDropDown = uidropdown(app.InteractiveModelSetupPanel);
            app.VariableofInterestDropDown.ValueChangedFcn = createCallbackFcn(app, @VariableofInterestDropDownValueChanged, true);
            app.VariableofInterestDropDown.Enable = 'off';
            app.CovariatesListBox = uilistbox(app.InteractiveModelSetupPanel);
            app.CovariatesListBox.Multiselect = 'on';
            app.CovariatesListBox.ValueChangedFcn = createCallbackFcn(app, @CovariatesListBoxValueChanged, true);
            app.CovariatesListBox.Enable = 'off';
            app.CovariatesListBox.Value = {'Item 1'};
            app.ConnthresholdSlider = uislider(app.InteractiveModelSetupPanel);
            app.ConnthresholdSlider.Limits = [0 49];
            app.ConnthresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ConnthresholdSliderValueChanged, true);
            app.ConnthresholdSlider.ValueChangingFcn = createCallbackFcn(app, @ConnthresholdSliderValueChanging, true);
            app.ConnthresholdSlider.Enable = 'off';
            app.ConnthresholdSlider.Value = 20;
            app.ConnthresholdLabel = uilabel(app.InteractiveModelSetupPanel);
            app.ConnthresholdLabel.Enable = 'off';
            app.ConnthresholdLabel.Text = {'Tracts should be connected '; 'to at least % of VTAs'};
            app.EfieldthresholdSlider = uislider(app.InteractiveModelSetupPanel);
            app.EfieldthresholdSlider.Limits = [1 40];
            app.EfieldthresholdSlider.ValueChangedFcn = createCallbackFcn(app, @EfieldthresholdSliderValueChanged, true);
            app.EfieldthresholdSlider.ValueChangingFcn = createCallbackFcn(app, @EfieldthresholdSliderValueChanging, true);
            app.EfieldthresholdSlider.Enable = 'off';
            app.EfieldthresholdSlider.Value = 2.5;
            app.EfieldthresholdLabel = uilabel(app.InteractiveModelSetupPanel);
            app.EfieldthresholdLabel.Enable = 'off';
            app.EfieldthresholdLabel.Text = {'Tracts are modulated > '; 'sum E-Field of  V/m'};
            app.CovariatesCheckBox = uicheckbox(app.InteractiveModelSetupPanel);
            app.CovariatesCheckBox.ValueChangedFcn = createCallbackFcn(app, @CovariatesCheckBoxValueChanged, true);
            app.CovariatesCheckBox.Enable = 'off';
            app.CovariatesCheckBox.Text = 'Covariates';
            app.Efieldmetric = uidropdown(app.InteractiveModelSetupPanel);
            app.Efieldmetric.Items = {'Sum', 'Mean', 'Peak', 'Peak 5%'};
            app.Efieldmetric.ValueChangedFcn = createCallbackFcn(app, @EfieldmetricValueChanged, true);
            app.Efieldmetric.Enable = 'off';
            app.Efieldmetric.Value = 'Sum';
            app.ModelSetupDropDownLabel = uilabel(app.InteractiveModelSetupPanel);
            app.ModelSetupDropDownLabel.Enable = 'off';
            app.ModelSetupDropDownLabel.Text = 'Model Setup';
            app.ModelSetupDropDown = uidropdown(app.InteractiveModelSetupPanel);
            app.ModelSetupDropDown.Items = {'T-Tests / VTAs (Baldermann 2019)', 'Correlations / E-fields (Irmen 2020)', 'Plain Connections'};
            app.ModelSetupDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelSetupDropDownValueChanged, true);
            app.ModelSetupDropDown.Enable = 'off';
            app.ModelSetupDropDown.Value = 'T-Tests / VTAs (Baldermann 2019)';
            app.Corrtype = uidropdown(app.InteractiveModelSetupPanel);
            app.Corrtype.Items = {'Spearman', 'Pearson', 'Bend', 'Skipped Spearman', 'Skipped Pearson'};
            app.Corrtype.ValueChangedFcn = createCallbackFcn(app, @CorrtypeValueChanged, true);
            app.Corrtype.Enable = 'off';
            app.Corrtype.Value = 'Spearman';
            app.SubcohortsDropDownLabel = uilabel(app.InteractiveModelSetupPanel);
            app.SubcohortsDropDownLabel.Enable = 'off';
            app.SubcohortsDropDownLabel.Text = 'Subcohorts';
            app.SubcohortsDropDown = uidropdown(app.InteractiveModelSetupPanel);
            app.SubcohortsDropDown.Items = {'Choose...', 'All Patients', 'Set from Selection', 'Inverse Set from Selection'};
            app.SubcohortsDropDown.ValueChangedFcn = createCallbackFcn(app, @SubcohortsDropDownValueChanged, true);
            app.SubcohortsDropDown.Enable = 'off';
            app.SubcohortsDropDown.Value = 'Choose...';
            app.BusyLampLabel = uilabel(app.UIFigure);
            app.BusyLampLabel.Text = 'Busy';
            app.BusyLamp = uilamp(app.UIFigure);
            app.VisualizationThresholdsPanel = uipanel(app.UIFigure);
            app.VisualizationThresholdsPanel.Title = 'Visualization & Thresholds';
            app.ColorButtonPositive = uibutton(app.VisualizationThresholdsPanel, 'push');
            app.ColorButtonPositive.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPositivePushed, true);
            app.ColorButtonPositive.Enable = 'off';
            app.ColorButtonPositive.Text = 'Color';
            app.ColorButtonNegative = uibutton(app.VisualizationThresholdsPanel, 'push');
            app.ColorButtonNegative.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonNegativePushed, true);
            app.ColorButtonNegative.Enable = 'off';
            app.ColorButtonNegative.Text = 'Color';
            app.ShowPositiveFibersCheckBox = uicheckbox(app.VisualizationThresholdsPanel);
            app.ShowPositiveFibersCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowPositiveFibersCheckBoxValueChanged, true);
            app.ShowPositiveFibersCheckBox.Enable = 'off';
            app.ShowPositiveFibersCheckBox.Text = 'Show Positive Fibers';
            app.ShowPositiveFibersCheckBox.Value = true;
            app.ShowNegativeFibersCheckBox = uicheckbox(app.VisualizationThresholdsPanel);
            app.ShowNegativeFibersCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowNegativeFibersCheckBoxValueChanged, true);
            app.ShowNegativeFibersCheckBox.Enable = 'off';
            app.ShowNegativeFibersCheckBox.Text = 'Show Negative Fibers';
            app.SplitColorbyGroupVariableCheckBox = uicheckbox(app.VisualizationThresholdsPanel);
            app.SplitColorbyGroupVariableCheckBox.ValueChangedFcn = createCallbackFcn(app, @SplitColorbyGroupVariableCheckBoxValueChanged, true);
            app.SplitColorbyGroupVariableCheckBox.Enable = 'off';
            app.SplitColorbyGroupVariableCheckBox.Text = 'Split & Color by Group Variable';
            app.ExportasAtlasButton = uibutton(app.VisualizationThresholdsPanel, 'push');
            app.ExportasAtlasButton.ButtonPushedFcn = createCallbackFcn(app, @ExportasAtlasButtonPushed, true);
            app.ExportasAtlasButton.Enable = 'off';
            app.ExportasAtlasButton.Text = 'Export as Atlas';
            app.ShowAmountSliderLabel = uilabel(app.VisualizationThresholdsPanel);
            app.ShowAmountSliderLabel.Enable = 'off';
            app.ShowAmountSliderLabel.Text = 'Show Amount';
            app.ShowAmountSliderPositive = uislider(app.VisualizationThresholdsPanel);
            app.ShowAmountSliderPositive.ValueChangedFcn = createCallbackFcn(app, @ShowAmountSliderPositiveValueChanged, true);
            app.ShowAmountSliderPositive.ValueChangingFcn = createCallbackFcn(app, @ShowAmountSliderPositiveValueChanging, true);
            app.ShowAmountSliderPositive.MinorTicks = [];
            app.ShowAmountSliderPositive.Enable = 'off';
            app.ShowAmountSliderPositive.Value = 25;
            app.LeftSliderLabel = uilabel(app.VisualizationThresholdsPanel);
            app.LeftSliderLabel.Enable = 'off';
            app.LeftSliderLabel.Text = 'Left';
            app.LeftSliderPositive = uislider(app.VisualizationThresholdsPanel);
            app.LeftSliderPositive.ValueChangedFcn = createCallbackFcn(app, @LeftSliderPositiveValueChanged, true);
            app.LeftSliderPositive.MinorTicks = [];
            app.LeftSliderPositive.Enable = 'off';
            app.LeftSliderPositive.Value = 25;
            app.RightSliderLabel = uilabel(app.VisualizationThresholdsPanel);
            app.RightSliderLabel.Enable = 'off';
            app.RightSliderLabel.Text = 'Right';
            app.RightSliderPositive = uislider(app.VisualizationThresholdsPanel);
            app.RightSliderPositive.ValueChangedFcn = createCallbackFcn(app, @RightSliderPositiveValueChanged, true);
            app.RightSliderPositive.MinorTicks = [];
            app.RightSliderPositive.Enable = 'off';
            app.RightSliderPositive.Value = 25;
            app.LeftSlider_2Label = uilabel(app.VisualizationThresholdsPanel);
            app.LeftSlider_2Label.Enable = 'off';
            app.LeftSlider_2Label.Text = 'Left';
            app.LeftSliderNegative = uislider(app.VisualizationThresholdsPanel);
            app.LeftSliderNegative.ValueChangedFcn = createCallbackFcn(app, @LeftSliderNegativeValueChanged, true);
            app.LeftSliderNegative.MinorTicks = [];
            app.LeftSliderNegative.Enable = 'off';
            app.LeftSliderNegative.Value = 25;
            app.ShowAmountSlider_2Label = uilabel(app.VisualizationThresholdsPanel);
            app.ShowAmountSlider_2Label.Enable = 'off';
            app.ShowAmountSlider_2Label.Text = 'Show Amount';
            app.ShowAmountSliderNegative = uislider(app.VisualizationThresholdsPanel);
            app.ShowAmountSliderNegative.ValueChangedFcn = createCallbackFcn(app, @ShowAmountSliderNegativeValueChanged, true);
            app.ShowAmountSliderNegative.ValueChangingFcn = createCallbackFcn(app, @ShowAmountSliderNegativeValueChanging, true);
            app.ShowAmountSliderNegative.MinorTicks = [];
            app.ShowAmountSliderNegative.Enable = 'off';
            app.ShowAmountSliderNegative.Value = 25;
            app.RightSlider_2Label = uilabel(app.VisualizationThresholdsPanel);
            app.RightSlider_2Label.Enable = 'off';
            app.RightSlider_2Label.Text = 'Right';
            app.RightSliderNegative = uislider(app.VisualizationThresholdsPanel);
            app.RightSliderNegative.ValueChangedFcn = createCallbackFcn(app, @RightSliderNegativeValueChanged, true);
            app.RightSliderNegative.ValueChangingFcn = createCallbackFcn(app, @RightSliderNegativeValueChanging, true);
            app.RightSliderNegative.MinorTicks = [];
            app.RightSliderNegative.Enable = 'off';
            app.RightSliderNegative.Value = 25;
            app.ConsiderSignificantFibersonlyCheckBox = uicheckbox(app.VisualizationThresholdsPanel);
            app.ConsiderSignificantFibersonlyCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConsiderSignificantFibersonlyCheckBoxValueChanged, true);
            app.ConsiderSignificantFibersonlyCheckBox.Enable = 'off';
            app.ConsiderSignificantFibersonlyCheckBox.Text = 'Consider Significant Fibers only';
            app.RefreshViewButton = uibutton(app.VisualizationThresholdsPanel, 'push');
            app.RefreshViewButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshViewButtonPushed, true);
            app.RefreshViewButton.Enable = 'off';
            app.RefreshViewButton.Text = 'Refresh View';
            app.AutoRefreshCheckBox = uicheckbox(app.VisualizationThresholdsPanel);
            app.AutoRefreshCheckBox.Enable = 'off';
            app.AutoRefreshCheckBox.Text = 'Auto-Refresh';
            app.AutoRefreshCheckBox.Value = true;
            app.levelEditFieldLabel = uilabel(app.VisualizationThresholdsPanel);
            app.levelEditFieldLabel.Enable = 'off';
            app.levelEditFieldLabel.Text = '𝝰-level';
            app.levelEditField = uieditfield(app.VisualizationThresholdsPanel, 'numeric');
            app.levelEditField.ValueChangedFcn = createCallbackFcn(app, @levelEditFieldValueChanged, true);
            app.levelEditField.Enable = 'off';
            app.levelEditField.Value = 0.05;
            app.MultTestStrategy = uidropdown(app.VisualizationThresholdsPanel);
            app.MultTestStrategy.Items = {'FDR', 'Bonferroni', 'Uncorrected'};
            app.MultTestStrategy.ValueChangedFcn = createCallbackFcn(app, @MultTestStrategyValueChanged, true);
            app.MultTestStrategy.Enable = 'off';
            app.MultTestStrategy.Value = 'FDR';
            app.AbsfiberamtButton = uibutton(app.VisualizationThresholdsPanel, 'push');
            app.AbsfiberamtButton.ButtonPushedFcn = createCallbackFcn(app, @AbsfiberamtButtonPushed, true);
            app.AbsfiberamtButton.Text = 'Abs. fiber amt';
            app.ColorBar = uiaxes(app.VisualizationThresholdsPanel);
            xlabel(app.ColorBar, 'X')
            ylabel(app.ColorBar, 'Y')
            app.CrossvalidationPredictionPanel = uipanel(app.UIFigure);
            app.CrossvalidationPredictionPanel.Title = 'Crossvalidation & Prediction';
            app.TrainOnListBoxLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.TrainOnListBoxLabel.Enable = 'off';
            app.TrainOnListBoxLabel.Text = 'Train On';
            app.TrainOnListBox = uilistbox(app.CrossvalidationPredictionPanel);
            app.TrainOnListBox.Multiselect = 'on';
            app.TrainOnListBox.ValueChangedFcn = createCallbackFcn(app, @TrainOnListBoxValueChanged, true);
            app.TrainOnListBox.Enable = 'off';
            app.TrainOnListBox.Value = {'Item 1'};
            app.PredictListBoxLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.PredictListBoxLabel.Enable = 'off';
            app.PredictListBoxLabel.Text = 'Predict';
            app.PredictListBox = uilistbox(app.CrossvalidationPredictionPanel);
            app.PredictListBox.Multiselect = 'on';
            app.PredictListBox.ValueChangedFcn = createCallbackFcn(app, @PredictListBoxValueChanged, true);
            app.PredictListBox.Enable = 'off';
            app.PredictListBox.Value = {'Item 1'};
            app.RunCVButton = uibutton(app.CrossvalidationPredictionPanel, 'push');
            app.RunCVButton.ButtonPushedFcn = createCallbackFcn(app, @RunCVButtonPushed, true);
            app.RunCVButton.Enable = 'off';
            app.RunCVButton.Text = 'Run';
            app.PosthoccorrectforgroupCheckBox = uicheckbox(app.CrossvalidationPredictionPanel);
            app.PosthoccorrectforgroupCheckBox.ValueChangedFcn = createCallbackFcn(app, @PosthoccorrectforgroupCheckBoxValueChanged, true);
            app.PosthoccorrectforgroupCheckBox.Enable = 'off';
            app.PosthoccorrectforgroupCheckBox.Text = 'Post-hoc correct for group';
            app.StrategyDropDownLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.StrategyDropDownLabel.Enable = 'off';
            app.StrategyDropDownLabel.Text = 'Strategy';
            app.StrategyDropDown = uidropdown(app.CrossvalidationPredictionPanel);
            app.StrategyDropDown.Items = {'Leave-Nothing-Out (Permutation-Based)', 'Leave-One-Patient-Out', 'Leave-One-Cohort-Out', 'k-fold (randomized)', 'Custom (Patients)', 'Custom (Cohorts)', 'Custom (Sets)', 'Custom (Subcohorts)'};
            app.StrategyDropDown.ValueChangedFcn = createCallbackFcn(app, @StrategyDropDownValueChanged, true);
            app.StrategyDropDown.Enable = 'off';
            app.StrategyDropDown.Value = 'Leave-Nothing-Out (Permutation-Based)';
            app.kEditFieldLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.kEditFieldLabel.Enable = 'off';
            app.kEditFieldLabel.Text = 'k';
            app.kEditField = uieditfield(app.CrossvalidationPredictionPanel, 'text');
            app.kEditField.ValueChangedFcn = createCallbackFcn(app, @kEditFieldValueChanged, true);
            app.kEditField.Enable = 'off';
            app.LiveVisualizeCheckBox = uicheckbox(app.CrossvalidationPredictionPanel);
            app.LiveVisualizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @LiveVisualizeCheckBoxValueChanged, true);
            app.LiveVisualizeCheckBox.Enable = 'off';
            app.LiveVisualizeCheckBox.Text = 'Live Visualize';
            app.BasePredictiononDropDownLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.BasePredictiononDropDownLabel.Enable = 'off';
            app.BasePredictiononDropDownLabel.Text = 'Base Prediction on ';
            app.BasePredictiononDropDown = uidropdown(app.CrossvalidationPredictionPanel);
            app.BasePredictiononDropDown.Items = {'Profile of Scores', 'Sum of Scores', 'Mean of Scores', 'Peak of Scores', 'Peak 5% of Scores', 'Histogram', 'z-scored Histogram'};
            app.BasePredictiononDropDown.ValueChangedFcn = createCallbackFcn(app, @BasePredictiononDropDownValueChanged, true);
            app.BasePredictiononDropDown.Enable = 'off';
            app.BasePredictiononDropDown.Value = 'Profile of Scores';
            app.ModelNormalizationDropDownLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.ModelNormalizationDropDownLabel.Enable = 'off';
            app.ModelNormalizationDropDownLabel.Text = 'Model Normalization';
            app.ModelNormalizationDropDown = uidropdown(app.CrossvalidationPredictionPanel);
            app.ModelNormalizationDropDown.Items = {'None', 'z-score', 'van Albada 2007'};
            app.ModelNormalizationDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelNormalizationDropDownValueChanged, true);
            app.ModelNormalizationDropDown.Enable = 'off';
            app.ModelNormalizationDropDown.Value = 'None';
            app.BinsEditFieldLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.BinsEditFieldLabel.Enable = 'off';
            app.BinsEditFieldLabel.Text = '#Bins';
            app.BinsEditField = uieditfield(app.CrossvalidationPredictionPanel, 'numeric');
            app.BinsEditField.ValueChangedFcn = createCallbackFcn(app, @BinsEditFieldValueChanged, true);
            app.BinsEditField.Enable = 'off';
            app.BinsEditField.Value = 15;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ea_discfiberexplorer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end